classdef ActivityMonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Elevator1SliderLabel   matlab.ui.control.Label
        Elevator1Slider        matlab.ui.control.Slider
        Elevator2SliderLabel   matlab.ui.control.Label
        Elevator2Slider        matlab.ui.control.Slider
        ElevatorDropDownLabel  matlab.ui.control.Label
        ElevatorDropDown       matlab.ui.control.DropDown
        WeightSliderLabel      matlab.ui.control.Label
        WeightSlider           matlab.ui.control.Slider
        SpeedSliderLabel       matlab.ui.control.Label
        SpeedSlider            matlab.ui.control.Slider
        MAINTAINButton         matlab.ui.control.Button
        RECOVERButton          matlab.ui.control.Button
        MaintenanceLabel       matlab.ui.control.Label
        SensorControllerLabel  matlab.ui.control.Label
        HeightEditFieldLabel   matlab.ui.control.Label
        HeightEditField        matlab.ui.control.NumericEditField
        DoorOpenLampLabel      matlab.ui.control.Label
        DoorOpenLamp           matlab.ui.control.Lamp
        DoorCloseLampLabel     matlab.ui.control.Label
        DoorCloseLamp          matlab.ui.control.Lamp
        OverweightLampLabel    matlab.ui.control.Label
        OverweightLamp         matlab.ui.control.Lamp
        EmergencyLampLabel     matlab.ui.control.Label
        EmergencyLamp          matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        hElevators % Description
        hElevator
        hSystemController % Description
    end
    
    methods (Access = public)
        
        function updateState(app)
            app.Elevator1Slider.Value = app.hElevators(1).height;
            app.Elevator2Slider.Value = app.hElevators(2).height;
            
            value = app.ElevatorDropDown.Value;
            if value == '1'
                app.hElevator = app.hElevators(1);
            elseif value == '2'
                app.hElevator = app.hElevators(2);
            end
            
            app.HeightEditField.Value = app.hElevator.height;
            
            app.updateSpeed();
            app.updateSignalLights();
            
            app.doorOpen();
        end
        
        function updateSpeed(app)
            app.SpeedSlider.Value = app.hElevator.velocity * app.hElevator.moving_direction;  % Statement - Tcover1.5.1.1
        end
        
        function updateSignalLights(app)
            % Overweight
            if app.hElevator.over_weight == 1  % Branch - Tcover1.3.2.6
                app.OverweightLamp.Color = [1,0,0];
            else  % Branch - Tcover1.3.2.7
                app.OverweightLamp.Color = [0.8,0.8,0.8];
            end
            % Emergency
            if app.hElevator.emergency == 1  % Branch - Tcover1.3.2.8
                app.EmergencyLamp.Color = [1,0,0];
            else  % Branch - Tcover1.3.2.9
                app.EmergencyLamp.Color = [0.8,0.8,0.8];
            end
        end
        
        function doorOpen(app)            
            % If need reset
            if app.hElevator.door_open == 1  % Branch - Tcover1.5.2.1
                % Opening
                if app.hElevator.timer_door-1 < 1  % Branch - Tcover1.5.2.2
                    app.DoorOpenLamp.Color = [1,0,0];
                    app.DoorCloseLamp.Color = [0.8,0.8,0.8];
                % Is Open
                elseif 1 <= app.hElevator.timer_door-1 && app.hElevator.timer_door-1 < 6  % Branch - Tcover1.5.2.3
                    app.DoorOpenLamp.Color = [0,1,0];
                % Closing
                elseif 6 <= app.hElevator.timer_door-1 && app.hElevator.timer_door-1 < 7  % Branch - Tcover1.5.2.4
                    app.DoorOpenLamp.Color = [0.8,0.8,0.8];
                    app.DoorCloseLamp.Color = [1,0,0];
                % Close
                elseif app.hElevator.timer_door-1 >= 7  % Branch - Tcover1.5.2.5
                    app.DoorCloseLamp.Color = [0,1,0];
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Elevator1Slider.Value = 1;
            app.Elevator2Slider.Value = 1;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.hSystemController.t);
            delete(app.hSystemController.t);
            delete(app);
        end

        % Button pushed function: MAINTAINButton
        function MAINTAINButtonPushed(app, event)
            app.MAINTAINButton.BackgroundColor = [0.9,0,0];
            app.MAINTAINButton.FontColor = [1,1,1];
            app.hElevator.maintain = 1;
        end

        % Button pushed function: RECOVERButton
        function RECOVERButtonPushed(app, event)
            app.MAINTAINButton.BackgroundColor = [0.96,0.96,0.96];
            app.MAINTAINButton.FontColor = [1,0,0];
            app.hElevator.maintain = 0;
        end

        % Value changed function: ElevatorDropDown
        function ElevatorDropDownValueChanged(app, event)
            value = app.ElevatorDropDown.Value;
            if value == '1'
                app.hElevator = app.hElevators(1);
            elseif value == '2'
                app.hElevator = app.hElevators(2);
            end
        end

        % Value changed function: WeightSlider
        function WeightSliderValueChanged(app, event)
            value = app.WeightSlider.Value;
            if value > app.hElevator.weight_limit
                app.hElevator.over_weight = 1;
            elseif value <= app.hElevator.weight_limit
                app.hElevator.over_weight = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Elevator1SliderLabel = uilabel(app.UIFigure);
            app.Elevator1SliderLabel.Text = 'Elevator 1';
            app.Elevator1Slider = uislider(app.UIFigure);
            app.Elevator1Slider.Limits = [0.9 3.1];
            app.Elevator1Slider.MajorTicks = [1 1.5 2 2.5 3];
            app.Elevator1Slider.MajorTickLabels = {'1', '1.5', '2', '2.5', '3'};
            app.Elevator1Slider.Value = 1;
            app.Elevator2SliderLabel = uilabel(app.UIFigure);
            app.Elevator2SliderLabel.Text = 'Elevator 2';
            app.Elevator2Slider = uislider(app.UIFigure);
            app.Elevator2Slider.Limits = [-0.1 3.1];
            app.Elevator2Slider.MajorTicks = [0 0.5 1 1.5 2 2.5 3];
            app.Elevator2Slider.MajorTickLabels = {'B', '0.5', '1', '1.5', '2', '2.5', '3'};
            app.ElevatorDropDownLabel = uilabel(app.UIFigure);
            app.ElevatorDropDownLabel.Text = 'Elevator';
            app.ElevatorDropDown = uidropdown(app.UIFigure);
            app.ElevatorDropDown.Items = {'1', '2'};
            app.ElevatorDropDown.ValueChangedFcn = createCallbackFcn(app, @ElevatorDropDownValueChanged, true);
            app.ElevatorDropDown.Value = '1';
            app.WeightSliderLabel = uilabel(app.UIFigure);
            app.WeightSliderLabel.Text = 'Weight';
            app.WeightSlider = uislider(app.UIFigure);
            app.WeightSlider.Limits = [0 1200];
            app.WeightSlider.MajorTicks = [0 300 600 900 1200];
            app.WeightSlider.MajorTickLabels = {'0', '300', '600', '900', '1200'};
            app.WeightSlider.ValueChangedFcn = createCallbackFcn(app, @WeightSliderValueChanged, true);
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Limits = [-0.3 0.3];
            app.SpeedSlider.MajorTicks = [-0.3 -0.2 -0.1 0 0.1 0.2 0.3];
            app.MAINTAINButton = uibutton(app.UIFigure, 'push');
            app.MAINTAINButton.ButtonPushedFcn = createCallbackFcn(app, @MAINTAINButtonPushed, true);
            app.MAINTAINButton.Text = 'MAINTAIN';
            app.RECOVERButton = uibutton(app.UIFigure, 'push');
            app.RECOVERButton.ButtonPushedFcn = createCallbackFcn(app, @RECOVERButtonPushed, true);
            app.RECOVERButton.Text = 'RECOVER';
            app.MaintenanceLabel = uilabel(app.UIFigure);
            app.MaintenanceLabel.Text = 'Maintenance';
            app.SensorControllerLabel = uilabel(app.UIFigure);
            app.SensorControllerLabel.Text = 'Sensor & Controller';
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.DoorOpenLampLabel = uilabel(app.UIFigure);
            app.DoorOpenLampLabel.Text = 'Door Open';
            app.DoorOpenLamp = uilamp(app.UIFigure);
            app.DoorCloseLampLabel = uilabel(app.UIFigure);
            app.DoorCloseLampLabel.Text = 'Door Close';
            app.DoorCloseLamp = uilamp(app.UIFigure);
            app.OverweightLampLabel = uilabel(app.UIFigure);
            app.OverweightLampLabel.Text = 'Overweight';
            app.OverweightLamp = uilamp(app.UIFigure);
            app.EmergencyLampLabel = uilabel(app.UIFigure);
            app.EmergencyLampLabel.Text = 'Emergency';
            app.EmergencyLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ActivityMonitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end