classdef CarUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        backImage            matlab.ui.control.Image
        F3_0                 matlab.ui.control.Image
        F2_0                 matlab.ui.control.Image
        F1_0                 matlab.ui.control.Image
        FB_0                 matlab.ui.control.Image
        close_0              matlab.ui.control.Image
        open_0               matlab.ui.control.Image
        E_0                  matlab.ui.control.Image
        E_1                  matlab.ui.control.Image
        close_1              matlab.ui.control.Image
        open_1               matlab.ui.control.Image
        F3_1                 matlab.ui.control.Image
        F2_1                 matlab.ui.control.Image
        F1_1                 matlab.ui.control.Image
        FB_1                 matlab.ui.control.Image
        floorDisplay         matlab.ui.control.Label
        DoorOpenLampLabel    matlab.ui.control.Label
        DoorOpenLamp         matlab.ui.control.Lamp
        DoorCloseLampLabel   matlab.ui.control.Label
        DoorCloseLamp        matlab.ui.control.Lamp
        ElevatorLabel        matlab.ui.control.Label
        OverweightLampLabel  matlab.ui.control.Label
        OverweightLamp       matlab.ui.control.Lamp
        EmergencyLampLabel   matlab.ui.control.Label
        EmergencyLamp        matlab.ui.control.Lamp
        left_door            matlab.ui.control.Label
        right_door           matlab.ui.control.Label
    end

    
    properties (Access = public)
        hElevator % Description
    end
    
    methods (Access = public)
        
        function init(app)
            if app.hElevator.name == "Elevator 1"
                app.FB_0.Visible = 0;
            end
        end
        
        function updateState(app)
            app.ElevatorLabel.Text = app.hElevator.name;
            app.updateDisplay();
            app.doorOpen();
        end
        
        function floorCall(app, target_floor)
        % Add the call target floor to the Elevator moving queue
            if target_floor <= app.hElevator.height + 0.0  % Branch - Tcover1.3.1.1
                app.hElevator.moving_down_queue = [app.hElevator.moving_down_queue target_floor];
                app.hElevator.moving_down_queue = sort(app.hElevator.moving_down_queue, 'descend');
            elseif target_floor > app.hElevator.height - 0.0  % Branch - Tcover1.3.1.2
                app.hElevator.moving_up_queue = [app.hElevator.moving_up_queue target_floor];
                app.hElevator.moving_up_queue = sort(app.hElevator.moving_up_queue, 'ascend');
            end
        end
        
        function updateDisplay(app)
            % Moving direction
            if app.hElevator.moving_direction == 1  % Branch - Tcover1.3.2.1
                moving_direction = '▲';
            elseif app.hElevator.moving_direction == -1  % Branch - Tcover1.3.2.2
                moving_direction = '▼';
            else  % Branch - Tcover1.3.2.3
                moving_direction = '';
            end
            
            % Floor display
            if app.hElevator.floor == 0  % Branch - Tcover1.3.2.4
                app.floorDisplay.Text = [moving_direction, 'B'];
            else  % Branch - Tcover1.3.2.5
                app.floorDisplay.Text = [moving_direction, num2str(app.hElevator.floor)];
            end
            % Overweight
            if app.hElevator.over_weight == 1  % Branch - Tcover1.3.2.6
                app.OverweightLamp.Color = [1,0,0];
            else  % Branch - Tcover1.3.2.7
                app.OverweightLamp.Color = [0.8,0.8,0.8];
            end
            % Emergency
            if app.hElevator.emergency == 1  % Branch - Tcover1.3.2.8
                app.EmergencyLamp.Color = [1,0,0];
            else  % Branch - Tcover1.3.2.9
                app.EmergencyLamp.Color = [0.8,0.8,0.8];
            end
            % Maintain
            if app.hElevator.maintain == 1  % Branch - Tcover1.3.2.10
                app.floorDisplay.Text = "M";
            end
            % Button display
            if app.hElevator.moving_direction == 0  % Branch - Tcover1.3.2.11
                if abs(app.hElevator.floor - app.hElevator.height) < 0.000001  % Branch - Tcover1.3.2.12
                    if app.hElevator.floor == 0  % Branch - Tcover1.3.2.13
                        app.FB_0.Visible = 1;
                        app.FB_1.Visible = 0;
                    else  % Branch - Tcover1.3.2.14
                        floor = num2str(app.hElevator.floor);
                        eval(['app.F',floor,'_0.Visible = 1;']);
                        eval(['app.F',floor,'_1.Visible = 0;']);
                    end
                end
            end
        end
        
        function doorOpen(app)            
            % If need reset
            if app.hElevator.timer_door >= 8  % Branch - Tcover1.3.3.1
                app.hElevator.door_open = 0;
                app.hElevator.timer_door = 0;
                app.hElevator.moving_direction = app.hElevator.direction_store;
                app.hElevator.direction_store = 0;
                app.hElevator.movingDirector();   % get direction
            end
            if app.hElevator.door_open == 1  % Branch - Tcover1.3.3.2
                % Store moving_direction
                if app.hElevator.moving_direction ~= 0 && app.hElevator.direction_store == 0  % Branch - Tcover1.3.3.3
                    app.hElevator.moving_direction = 0;
                end
                % Opening
                if app.hElevator.timer_door < 1  % Branch - Tcover1.3.3.4
                    app.hElevator.door_status = "opening";
                    app.open_1.Visible = 1;
                    app.open_0.Visible = 0;
                    app.close_0.Visible = 1;
                    app.close_1.Visible = 0;
                    app.DoorOpenLamp.Color = [1,0,0];
                    app.DoorCloseLamp.Color = [0.8,0.8,0.8];
                    app.left_door.Position = [46,33,99,254];
                    app.right_door.Position = [265,33,99,254];
                % Is Open
                elseif 1 <= app.hElevator.timer_door && app.hElevator.timer_door < 6  % Branch - Tcover1.3.3.5
                    app.hElevator.door_status = "open";
                    app.open_1.Visible = 0;
                    app.open_0.Visible = 1;
                    app.close_0.Visible = 1;
                    app.close_1.Visible = 0;
                    app.DoorOpenLamp.Color = [0,1,0];
                    app.DoorCloseLamp.Color = [0.8,0.8,0.8];
                    app.left_door.Position = [46,33,29,254];
                    app.right_door.Position = [335,33,29,254];
                % Closing
                elseif 6 <= app.hElevator.timer_door && app.hElevator.timer_door < 7  % Branch - Tcover1.3.3.6
                    app.hElevator.door_status = "closing";
                    app.open_1.Visible = 0;
                    app.open_0.Visible = 1;
                    app.close_1.Visible = 1;
                    app.close_0.Visible = 0;
                    app.DoorOpenLamp.Color = [0.8,0.8,0.8];
                    app.DoorCloseLamp.Color = [1,0,0];
                    app.left_door.Position = [46,33,99,254];
                    app.right_door.Position = [265,33,99,254];
                % Close
                elseif app.hElevator.timer_door >= 7  % Branch - Tcover1.3.3.7
                    app.hElevator.door_status = "close";
                    app.open_1.Visible = 0;
                    app.open_0.Visible = 1;
                    app.close_0.Visible = 1;
                    app.close_1.Visible = 0;
                    app.DoorCloseLamp.Color = [0,1,0];
                    app.DoorOpenLamp.Color = [0.8,0.8,0.8];
                    app.left_door.Position = [46,33,169,254];
                    app.right_door.Position = [195,33,169,254];
                end
                % Update timer
                app.hElevator.timer_door = app.hElevator.timer_door + 1;
            end
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FB_0.Visible = 1;
            app.F1_0.Visible = 1;
            app.F2_0.Visible = 1;
            app.F3_0.Visible = 1;
            app.close_0.Visible = 1;
            app.open_0.Visible = 1;
            app.E_0.Visible = 1;
            
            app.FB_1.Visible = 0;
            app.F1_1.Visible = 0;
            app.F2_1.Visible = 0;
            app.F3_1.Visible = 0;
            app.close_1.Visible = 0;
            app.open_1.Visible = 0;
            app.E_1.Visible = 0;
            
            app.DoorCloseLamp.Color = [0,1,0];
        end

        % Image clicked function: FB_0
        function FB_0ImageClicked(app, event)
            app.FB_0.Visible = 0;
            app.FB_1.Visible = 1;
            app.floorCall(0);
        end

        % Image clicked function: F1_0
        function F1_0ImageClicked(app, event)
            app.F1_0.Visible = 0;
            app.F1_1.Visible = 1;
            app.floorCall(1);
        end

        % Image clicked function: F2_0
        function F2_0ImageClicked(app, event)
            app.F2_0.Visible = 0;
            app.F2_1.Visible = 1;
            app.floorCall(2);
        end

        % Image clicked function: F3_0
        function F3_0ImageClicked(app, event)
            app.F3_0.Visible = 0;
            app.F3_1.Visible = 1;
            app.floorCall(3);
        end

        % Image clicked function: E_0
        function E_0ImageClicked(app, event)
            app.E_0.Visible = 0;
            app.E_1.Visible = 1;
            app.hElevator.emergency = 1;
        end

        % Image clicked function: E_1
        function E_1ImageClicked(app, event)
            app.E_1.Visible = 0;
            app.E_0.Visible = 1;
            app.hElevator.emergency = 0;
        end

        % Image clicked function: open_0
        function open_0ImageClicked(app, event)

            app.open_0.Visible = 0;
            app.open_1.Visible = 1;
            
            if app.hElevator.moving_direction == 0
                if app.hElevator.door_open == 1
                    app.hElevator.timer_door = 2;
                elseif app.hElevator.door_open == 0
                    app.hElevator.timer_door = 0;
                    app.hElevator.door_open = 1;
                end
            else
                app.open_1.Visible = 0;
                app.open_0.Visible = 1;
            end


        end

        % Image clicked function: close_0
        function close_0ImageClicked(app, event)
            
            app.close_0.Visible = 0;
            app.close_1.Visible = 1;
            
            if app.hElevator.moving_direction == 0
                if app.hElevator.door_status == "open" || app.hElevator.door_status == "opening"
                    app.hElevator.timer_door = 6;
                else
                    app.close_1.Visible = 0;
                    app.close_0.Visible = 1;
                end
            else
                app.close_1.Visible = 0;
                app.close_0.Visible = 1;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.backImage = uiimage(app.UIFigure);
            app.backImage.ImageSource = 'back.png';
            app.F3_0 = uiimage(app.UIFigure);
            app.F3_0.ImageClickedFcn = createCallbackFcn(app, @F3_0ImageClicked, true);
            app.F3_0.ImageSource = '3_0.png';
            app.F2_0 = uiimage(app.UIFigure);
            app.F2_0.ImageClickedFcn = createCallbackFcn(app, @F2_0ImageClicked, true);
            app.F2_0.ImageSource = '2_0.png';
            app.F1_0 = uiimage(app.UIFigure);
            app.F1_0.ImageClickedFcn = createCallbackFcn(app, @F1_0ImageClicked, true);
            app.F1_0.ImageSource = '1_0.png';
            app.FB_0 = uiimage(app.UIFigure);
            app.FB_0.ImageClickedFcn = createCallbackFcn(app, @FB_0ImageClicked, true);
            app.FB_0.ImageSource = 'B_0.png';
            app.close_0 = uiimage(app.UIFigure);
            app.close_0.ImageClickedFcn = createCallbackFcn(app, @close_0ImageClicked, true);
            app.close_0.ImageSource = 'close_0.png';
            app.open_0 = uiimage(app.UIFigure);
            app.open_0.ImageClickedFcn = createCallbackFcn(app, @open_0ImageClicked, true);
            app.open_0.ImageSource = 'open_0.png';
            app.E_0 = uiimage(app.UIFigure);
            app.E_0.ImageClickedFcn = createCallbackFcn(app, @E_0ImageClicked, true);
            app.E_0.ImageSource = 'EMG_0.png';
            app.E_1 = uiimage(app.UIFigure);
            app.E_1.ImageClickedFcn = createCallbackFcn(app, @E_1ImageClicked, true);
            app.E_1.ImageSource = 'EMG_1.png';
            app.close_1 = uiimage(app.UIFigure);
            app.close_1.ImageSource = 'close_1.png';
            app.open_1 = uiimage(app.UIFigure);
            app.open_1.ImageSource = 'open_1.png';
            app.F3_1 = uiimage(app.UIFigure);
            app.F3_1.ImageSource = '3_1.png';
            app.F2_1 = uiimage(app.UIFigure);
            app.F2_1.ImageSource = '2_1.png';
            app.F1_1 = uiimage(app.UIFigure);
            app.F1_1.ImageSource = '1_1.png';
            app.FB_1 = uiimage(app.UIFigure);
            app.FB_1.ImageSource = 'B_1.png';
            app.floorDisplay = uilabel(app.UIFigure);
            app.floorDisplay.Text = '1';
            app.DoorOpenLampLabel = uilabel(app.UIFigure);
            app.DoorOpenLampLabel.Text = 'Door Open';
            app.DoorOpenLamp = uilamp(app.UIFigure);
            app.DoorCloseLampLabel = uilabel(app.UIFigure);
            app.DoorCloseLampLabel.Text = 'Door Close';
            app.DoorCloseLamp = uilamp(app.UIFigure);
            app.ElevatorLabel = uilabel(app.UIFigure);
            app.ElevatorLabel.Text = 'Elevator';
            app.OverweightLampLabel = uilabel(app.UIFigure);
            app.OverweightLampLabel.Text = 'Overweight';
            app.OverweightLamp = uilamp(app.UIFigure);
            app.EmergencyLampLabel = uilabel(app.UIFigure);
            app.EmergencyLampLabel.Text = 'Emergency';
            app.EmergencyLamp = uilamp(app.UIFigure);
            app.left_door = uilabel(app.UIFigure);
            app.left_door.Text = '';
            app.right_door = uilabel(app.UIFigure);
            app.right_door.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CarUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end