classdef FloorUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        F1_down_0     matlab.ui.control.Image
        F1Label       matlab.ui.control.Label
        F1_up_0       matlab.ui.control.Image
        F1_down_1     matlab.ui.control.Image
        F1_up_1       matlab.ui.control.Image
        F1_display_1  matlab.ui.control.Label
        F1_display_2  matlab.ui.control.Label
        door_1_1      matlab.ui.control.Label
        door_1_2      matlab.ui.control.Label
        door_2_1      matlab.ui.control.Label
        door_2_2      matlab.ui.control.Label
    end

    
    properties (Access = public)
        hElevators % Description
        hSystemController % Description
        floor
        door_1
        door_2
    end
    
    methods (Access = public)
        
        function init(app)
            app.F1Label.Text = num2str(app.floor)+"F";
            
            if app.floor == 0
                app.F1Label.Text = "BF";
            end
            
            if app.floor > 0
                app.F1_down_0.Visible = 1;
                app.door_1_1.Visible = 1;
                app.door_1_2.Visible = 1;
            end
            if app.floor < 3
                app.F1_up_0.Visible = 1;
            end
            
            
        end
        
        function updateState(app)
            % Update doors
            if app.hElevators(1).floor == app.floor
                app.door_1 = app.hElevators(1).door_status;
            end
            if app.hElevators(2).floor == app.floor
                app.door_2 = app.hElevators(2).door_status;
            end
            % Update floor display
            app.updateDispaly();
            app.UpdateDoorStatus();
            
        end
        
        function updateDispaly(app)
            % Elevator 1
            if app.hElevators(1).moving_direction == 1  % Branch - Tcover1.4.1.1
                moving_direction = '▲';
            elseif app.hElevators(1).moving_direction == -1  % Branch - Tcover1.4.1.2
                moving_direction = '▼';
            else  % Branch - Tcover1.4.1.3
                moving_direction = '';
            end
            if app.hElevators(1).maintain == 1  % Branch - Tcover1.4.1.4
                app.F1_display_1.Text = "M";
            else  % Branch - Tcover1.4.1.5
                if app.hElevators(1).floor == 0  % Branch - Tcover1.4.1.6
                    app.F1_display_1.Text = [moving_direction, 'B'];
                else  % Branch - Tcover1.4.1.7
                    app.F1_display_1.Text = [moving_direction, num2str(app.hElevators(1).floor)];
                end
            end
            
            % Elevator 2
            if app.hElevators(2).moving_direction == 1
                moving_direction = '▲';
            elseif app.hElevators(2).moving_direction == -1
                moving_direction = '▼';
            else
                moving_direction = '';
            end
            if app.hElevators(2).maintain == 1
                app.F1_display_2.Text = "M";
            else
                if app.hElevators(2).floor == 0
                    app.F1_display_2.Text = [moving_direction, 'B'];
                else
                    app.F1_display_2.Text = [moving_direction, num2str(app.hElevators(2).floor)];
                end
            end 
        end
        
        function call(app, direction, floor)
            % Call one elavator
            app.hSystemController.scheduler([direction, floor]);
        end
        
        function lightOff(app, direction, floor)
            % Set the button status to 0
            if floor == app.floor  % Branch - Tcover1.4.2.1
                if direction == 1 && app.floor < 3   % up  % Branch - Tcover1.4.2.2
                    app.F1_up_0.Visible = 1;
                    app.F1_up_1.Visible = 0;
                elseif direction == -1 && app.floor > 0  % down  % Branch - Tcover1.4.2.3
                    app.F1_down_0.Visible = 1;
                    app.F1_down_1.Visible = 0;
                end
            end
        end
        
        function UpdateDoorStatus(app)
            % Door 1
            if app.door_1 == "close"
                app.door_1_1.Position = [164 13 39 55];
                app.door_1_2.Position = [202 13 39 55];
            elseif app.door_1 == "closing" || app.door_1 == "opening"
                app.door_1_1.Position = [164 13 24 55];
                app.door_1_2.Position = [217 13 24 55];
            elseif app.door_1 == "open"
                app.door_1_1.Position = [164 13 10 55];
                app.door_1_2.Position = [231 13 10 55];
            end
            % Door 2
            if app.door_2 == "close"
                app.door_2_1.Position = [458 13 39 55];
                app.door_2_2.Position = [496 13 39 55];
            elseif app.door_2 == "closing" || app.door_2 == "opening"
                app.door_2_1.Position = [458 13 24 55];
                app.door_2_2.Position = [511 13 24 55];
            elseif app.door_2 == "open"
                app.door_2_1.Position = [458 13 10 55];
                app.door_2_2.Position = [525 13 10 55];
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set buttons
            app.F1_down_0.Visible = 0;
            app.F1_up_0.Visible = 0;
            app.F1_down_1.Visible = 0;
            app.F1_up_1.Visible = 0;
            % Set doors
            app.door_1 = "close";
            app.door_2 = "close";
            app.door_1_1.Visible = 0;
            app.door_1_2.Visible = 0;
            app.door_2_1.Visible = 1;
            app.door_2_2.Visible = 1;
        end

        % Image clicked function: F1_down_0
        function F1_down_0ImageClicked(app, event)
            app.F1_down_0.Visible = 0;
            app.F1_down_1.Visible = 1;
            app.call("down", app.floor)
        end

        % Image clicked function: F1_up_0
        function F1_up_0ImageClicked(app, event)
            app.F1_up_0.Visible = 0;
            app.F1_up_1.Visible = 1;
            app.call("up", app.floor)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.F1_down_0 = uiimage(app.UIFigure);
            app.F1_down_0.ImageClickedFcn = createCallbackFcn(app, @F1_down_0ImageClicked, true);
            app.F1_down_0.ImageSource = 'down_0.png';
            app.F1Label = uilabel(app.UIFigure);
            app.F1Label.Text = '1 F';
            app.F1_up_0 = uiimage(app.UIFigure);
            app.F1_up_0.ImageClickedFcn = createCallbackFcn(app, @F1_up_0ImageClicked, true);
            app.F1_up_0.ImageSource = 'up_0.png';
            app.F1_down_1 = uiimage(app.UIFigure);
            app.F1_down_1.ImageSource = 'down_1.png';
            app.F1_up_1 = uiimage(app.UIFigure);
            app.F1_up_1.ImageSource = 'up_1.png';
            app.F1_display_1 = uilabel(app.UIFigure);
            app.F1_display_1.Text = '1';
            app.F1_display_2 = uilabel(app.UIFigure);
            app.F1_display_2.Text = '1';
            app.door_1_1 = uilabel(app.UIFigure);
            app.door_1_1.Text = '';
            app.door_1_2 = uilabel(app.UIFigure);
            app.door_1_2.Text = '';
            app.door_2_1 = uilabel(app.UIFigure);
            app.door_2_1.Text = '';
            app.door_2_2 = uilabel(app.UIFigure);
            app.door_2_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FloorUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end