classdef TrainPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TrainControlPanel               matlab.ui.container.Panel
        SpeedGaugeLabel                 matlab.ui.control.Label
        SpeedGauge                      matlab.ui.control.Gauge
        ControlPanel                    matlab.ui.container.Panel
        StopatnextstationButton         matlab.ui.control.Button
        AcceleratetocruisingspeedButton  matlab.ui.control.Button
        AcceleratetomaximumspeedButton  matlab.ui.control.Button
        TrainIDEditFieldLabel           matlab.ui.control.Label
        TrainIDEditField                matlab.ui.control.EditField
        MaximumSpeedEditFieldLabel      matlab.ui.control.Label
        MaximumSpeedEditField           matlab.ui.control.EditField
        NextStationEditFieldLabel       matlab.ui.control.Label
        NextStationEditField            matlab.ui.control.EditField
        CurrentStateEditFieldLabel      matlab.ui.control.Label
        CurrentStateEditField           matlab.ui.control.EditField
        CruisingSpeedEditFieldLabel     matlab.ui.control.Label
        CruisingSpeedEditField          matlab.ui.control.EditField
        CurrentSpeedEditFieldLabel      matlab.ui.control.Label
        CurrentSpeedEditField           matlab.ui.control.EditField
        AccelerationEditFieldLabel      matlab.ui.control.Label
        AccelerationEditField           matlab.ui.control.EditField
        LocationEditFieldLabel          matlab.ui.control.Label
        LocationEditField               matlab.ui.control.EditField
    end

    
    properties (Access = public)
        train
    end
    
    methods (Access = public)
        
        %    T1.6.1
        function updateState(app)
            app.SpeedGauge.Value = app.train.speed;
            app.NextStationEditField.Value = app.train.nextDest;
            app.CurrentSpeedEditField.Value = num2str(app.train.speed);
            app.AccelerationEditField.Value = num2str(app.train.acceleration);
            app.LocationEditField.Value = num2str(app.train.location);
            switch app.train.state
                case 0
                    %    Branch - Tcover 1.6.1.1
                    app.CurrentStateEditField.Value = 'stopped';
                case 1
                    %    Branch - Tcover 1.6.1.2
                    app.CurrentStateEditField.Value = 'accelerating';
                case 2
                    %    Branch - Tcover 1.6.1.3
                    app.CurrentStateEditField.Value = 'decelerating';
                case 3
                    %    Branch - Tcover 1.6.1.4
                    if app.train.speed == app.train.cruSpeed
                        %    Branch - Tcover 1.6.1.4.1
                        app.CurrentStateEditField.Value = 'cruising speed';
                    else
                        %    Branch - Tcover 1.6.1.4.2
                        app.CurrentStateEditField.Value = 'avoiding collision';
                    end
                case 4
                    %    Branch - Tcover 1.6.1.5
                    app.CurrentStateEditField.Value = 'maximum speed';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopatnextstationButton
        function StopatnextstationButtonPushed(app, event)
            app.train.stopAtNext();
        end

        % Button pushed function: AcceleratetocruisingspeedButton
        function AcceleratetocruisingspeedButtonPushed(app, event)
            app.train.accelerateToCruisingSpeed();
        end

        % Button pushed function: AcceleratetomaximumspeedButton
        function AcceleratetomaximumspeedButtonPushed(app, event)
            app.train.accelerateToMaximumSpeed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TrainControlPanel = uipanel(app.UIFigure);
            app.TrainControlPanel.Title = 'Train Control';
            app.SpeedGaugeLabel = uilabel(app.TrainControlPanel);
            app.SpeedGaugeLabel.Text = 'Speed';
            app.SpeedGauge = uigauge(app.TrainControlPanel, 'circular');
            app.SpeedGauge.Limits = [0 400];
            app.ControlPanel = uipanel(app.TrainControlPanel);
            app.ControlPanel.Title = 'Control Panel';
            app.StopatnextstationButton = uibutton(app.ControlPanel, 'push');
            app.StopatnextstationButton.ButtonPushedFcn = createCallbackFcn(app, @StopatnextstationButtonPushed, true);
            app.StopatnextstationButton.Text = 'Stop at next station';
            app.AcceleratetocruisingspeedButton = uibutton(app.ControlPanel, 'push');
            app.AcceleratetocruisingspeedButton.ButtonPushedFcn = createCallbackFcn(app, @AcceleratetocruisingspeedButtonPushed, true);
            app.AcceleratetocruisingspeedButton.Text = 'Accelerate to cruising speed';
            app.AcceleratetomaximumspeedButton = uibutton(app.ControlPanel, 'push');
            app.AcceleratetomaximumspeedButton.ButtonPushedFcn = createCallbackFcn(app, @AcceleratetomaximumspeedButtonPushed, true);
            app.AcceleratetomaximumspeedButton.Text = 'Accelerate to maximum speed';
            app.TrainIDEditFieldLabel = uilabel(app.TrainControlPanel);
            app.TrainIDEditFieldLabel.Text = 'Train ID';
            app.TrainIDEditField = uieditfield(app.TrainControlPanel, 'text');
            app.TrainIDEditField.Editable = 'off';
            app.MaximumSpeedEditFieldLabel = uilabel(app.TrainControlPanel);
            app.MaximumSpeedEditFieldLabel.Text = 'Maximum Speed';
            app.MaximumSpeedEditField = uieditfield(app.TrainControlPanel, 'text');
            app.MaximumSpeedEditField.Editable = 'off';
            app.NextStationEditFieldLabel = uilabel(app.TrainControlPanel);
            app.NextStationEditFieldLabel.Text = 'Next Station';
            app.NextStationEditField = uieditfield(app.TrainControlPanel, 'text');
            app.NextStationEditField.Editable = 'off';
            app.CurrentStateEditFieldLabel = uilabel(app.TrainControlPanel);
            app.CurrentStateEditFieldLabel.Text = 'Current State';
            app.CurrentStateEditField = uieditfield(app.TrainControlPanel, 'text');
            app.CurrentStateEditField.Editable = 'off';
            app.CruisingSpeedEditFieldLabel = uilabel(app.TrainControlPanel);
            app.CruisingSpeedEditFieldLabel.Text = 'Cruising Speed';
            app.CruisingSpeedEditField = uieditfield(app.TrainControlPanel, 'text');
            app.CruisingSpeedEditField.Editable = 'off';
            app.CurrentSpeedEditFieldLabel = uilabel(app.TrainControlPanel);
            app.CurrentSpeedEditFieldLabel.Text = 'Current Speed';
            app.CurrentSpeedEditField = uieditfield(app.TrainControlPanel, 'text');
            app.CurrentSpeedEditField.Editable = 'off';
            app.AccelerationEditFieldLabel = uilabel(app.TrainControlPanel);
            app.AccelerationEditFieldLabel.Text = 'Acceleration';
            app.AccelerationEditField = uieditfield(app.TrainControlPanel, 'text');
            app.AccelerationEditField.Editable = 'off';
            app.LocationEditFieldLabel = uilabel(app.TrainControlPanel);
            app.LocationEditFieldLabel.Text = 'Location';
            app.LocationEditField = uieditfield(app.TrainControlPanel, 'text');
            app.LocationEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrainPanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end