classdef Display < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SimulatorPanel             matlab.ui.container.Panel
        Railway                    matlab.ui.control.Label
        TimeEditFieldLabel         matlab.ui.control.Label
        TimeEditField              matlab.ui.control.NumericEditField
        StartLabel                 matlab.ui.control.Label
        S1Label                    matlab.ui.control.Label
        S2Label                    matlab.ui.control.Label
        S3Label                    matlab.ui.control.Label
        S4Label                    matlab.ui.control.Label
        S5Label                    matlab.ui.control.Label
        TermiLabel                 matlab.ui.control.Label
        S1Image                    matlab.ui.control.Image
        S2Image                    matlab.ui.control.Image
        S3Image                    matlab.ui.control.Image
        S4Image                    matlab.ui.control.Image
        S5Image                    matlab.ui.control.Image
        StartImage                 matlab.ui.control.Image
        TermiImage                 matlab.ui.control.Image
        Train1Image                matlab.ui.control.Image
        Train1Label                matlab.ui.control.Label
        Train2Image                matlab.ui.control.Image
        Train2Label                matlab.ui.control.Label
        Train3Image                matlab.ui.control.Image
        Train3Label                matlab.ui.control.Label
        Train4Image                matlab.ui.control.Image
        Train4Label                matlab.ui.control.Label
        Train5Image                matlab.ui.control.Image
        Train5Label                matlab.ui.control.Label
        S1Panel                    matlab.ui.container.Panel
        S1inButtonGroup            matlab.ui.container.ButtonGroup
        S1In0                      matlab.ui.control.ToggleButton
        S1In1                      matlab.ui.control.ToggleButton
        switchLampLabel            matlab.ui.control.Label
        S1InSwitchLamp             matlab.ui.control.Lamp
        S1In2                      matlab.ui.control.ToggleButton
        S1In3                      matlab.ui.control.ToggleButton
        S1outButtonGroup           matlab.ui.container.ButtonGroup
        S1Out0                     matlab.ui.control.ToggleButton
        S1Out1                     matlab.ui.control.ToggleButton
        switchLamp_2Label          matlab.ui.control.Label
        S1OutSwitchLamp            matlab.ui.control.Lamp
        S1Out2                     matlab.ui.control.ToggleButton
        S1Out3                     matlab.ui.control.ToggleButton
        Rail1EditFieldLabel        matlab.ui.control.Label
        S1Rail1                    matlab.ui.control.EditField
        Rail2EditFieldLabel        matlab.ui.control.Label
        S1Rail2                    matlab.ui.control.EditField
        Rail3EditFieldLabel        matlab.ui.control.Label
        S1Rail3                    matlab.ui.control.EditField
        S2Panel                    matlab.ui.container.Panel
        Rail1EditField_2Label      matlab.ui.control.Label
        S2Rail1                    matlab.ui.control.EditField
        Rail2EditField_2Label      matlab.ui.control.Label
        S2Rail2                    matlab.ui.control.EditField
        Rail3EditField_2Label      matlab.ui.control.Label
        S2Rail3                    matlab.ui.control.EditField
        S2inButtonGroup            matlab.ui.container.ButtonGroup
        S2In0                      matlab.ui.control.ToggleButton
        switchLamp_3Label          matlab.ui.control.Label
        S2InSwitchLamp             matlab.ui.control.Lamp
        S2In1                      matlab.ui.control.ToggleButton
        S2In2                      matlab.ui.control.ToggleButton
        S2In3                      matlab.ui.control.ToggleButton
        S2outButtonGroup           matlab.ui.container.ButtonGroup
        S2Out0                     matlab.ui.control.ToggleButton
        switchLamp_4Label          matlab.ui.control.Label
        S2OutSwitchLamp            matlab.ui.control.Lamp
        S2Out1                     matlab.ui.control.ToggleButton
        S2Out2                     matlab.ui.control.ToggleButton
        S2Out3                     matlab.ui.control.ToggleButton
        S3Panel                    matlab.ui.container.Panel
        Rail1EditField_3Label      matlab.ui.control.Label
        S3Rail1                    matlab.ui.control.EditField
        Rail2EditField_3Label      matlab.ui.control.Label
        S3Rail2                    matlab.ui.control.EditField
        Rail3EditField_3Label      matlab.ui.control.Label
        S3Rail3                    matlab.ui.control.EditField
        S3inButtonGroup            matlab.ui.container.ButtonGroup
        S3In0                      matlab.ui.control.ToggleButton
        S3In1                      matlab.ui.control.ToggleButton
        S3In2                      matlab.ui.control.ToggleButton
        switchLamp_5Label          matlab.ui.control.Label
        S3InSwitchLamp             matlab.ui.control.Lamp
        S3In3                      matlab.ui.control.ToggleButton
        S3outButtonGroup           matlab.ui.container.ButtonGroup
        S3Out0                     matlab.ui.control.ToggleButton
        S3Out1                     matlab.ui.control.ToggleButton
        S3Out2                     matlab.ui.control.ToggleButton
        switchLamp_6Label          matlab.ui.control.Label
        S3OutSwitchLamp            matlab.ui.control.Lamp
        S3Out3                     matlab.ui.control.ToggleButton
        S4Panel                    matlab.ui.container.Panel
        Rail1EditField_4Label      matlab.ui.control.Label
        S4Rail1                    matlab.ui.control.EditField
        Rail2EditField_4Label      matlab.ui.control.Label
        S4Rail2                    matlab.ui.control.EditField
        Rail3EditField_4Label      matlab.ui.control.Label
        S4Rail3                    matlab.ui.control.EditField
        S4inButtonGroup            matlab.ui.container.ButtonGroup
        S4In0                      matlab.ui.control.ToggleButton
        switchLamp_7Label          matlab.ui.control.Label
        S4InSwitchLamp             matlab.ui.control.Lamp
        S4In1                      matlab.ui.control.ToggleButton
        S4In2                      matlab.ui.control.ToggleButton
        S4In3                      matlab.ui.control.ToggleButton
        S4outButtonGroup           matlab.ui.container.ButtonGroup
        S4Out0                     matlab.ui.control.ToggleButton
        switchLamp_8Label          matlab.ui.control.Label
        S4OutSwitchLamp            matlab.ui.control.Lamp
        S4Out1                     matlab.ui.control.ToggleButton
        S4Out2                     matlab.ui.control.ToggleButton
        S4Out3                     matlab.ui.control.ToggleButton
        S5Panel                    matlab.ui.container.Panel
        Rail1EditField_5Label      matlab.ui.control.Label
        S5Rail1                    matlab.ui.control.EditField
        Rail2EditField_5Label      matlab.ui.control.Label
        S5Rail2                    matlab.ui.control.EditField
        Rail3EditField_5Label      matlab.ui.control.Label
        S5Rail3                    matlab.ui.control.EditField
        S5inButtonGroup            matlab.ui.container.ButtonGroup
        S5In0                      matlab.ui.control.ToggleButton
        S5In1                      matlab.ui.control.ToggleButton
        S5In2                      matlab.ui.control.ToggleButton
        switchLamp_9Label          matlab.ui.control.Label
        S5InSwitchLamp             matlab.ui.control.Lamp
        S5In3                      matlab.ui.control.ToggleButton
        S5outButtonGroup           matlab.ui.container.ButtonGroup
        S5Out0                     matlab.ui.control.ToggleButton
        S5Out1                     matlab.ui.control.ToggleButton
        S5Out2                     matlab.ui.control.ToggleButton
        switchLamp_10Label         matlab.ui.control.Label
        S5OutSwitchLamp            matlab.ui.control.Lamp
        S5Out3                     matlab.ui.control.ToggleButton
        Train6Label                matlab.ui.control.Label
        Train6Image                matlab.ui.control.Image
        ControlPanel               matlab.ui.container.Panel
        LoadInitialScheduleButton  matlab.ui.control.Button
        BeginSimulateButton        matlab.ui.control.Button
        StopSimulateButton         matlab.ui.control.Button
        SchedulePanel              matlab.ui.container.Panel
        UITable                    matlab.ui.control.Table
    end

    
    properties (Access = public)
        hController
        hSystem
        hEnvironment
        railwayStart %  start coordinate of railway
        railwayLength %  length of railway
        railwayEnd %  end coordinate of railway
        lengthPerMeter %  the length in display corresponding to unit distance in railway
    end
    
    methods (Access = public)
        
        function updateState(app)
            app.TimeEditField.Value = app.hSystem.time;
            for i=1:app.hEnvironment.stationNum
                app.showRailState(i);
            end
            for i=1:app.hEnvironment.trainNum
                train = app.hEnvironment.trains(i);
                app.moveTrain(i, train.location);
                if train.updateScheduleFlag == 1
                    realSchedule = train.realSchedule;
                    if train.currStation == "Start"
                        app.UITable.Data(2*i, 1) = realSchedule.StartLeaveTime;
                    elseif train.currStation == "S1"
                        prev = app.UITable.Data(2*i, 2);
                        app.UITable.Data(2*i, 2) = realSchedule.S1ArrivalTime;
                        app.UITable.Data(2*i, 3) = realSchedule.S1LeaveTime;
                        if prev == -1
                            app.UITable.Data(2*i, 13) = app.UITable.Data(2*i, 13) + max(0, app.UITable.Data(2*i, 2) - app.UITable.Data(2*i - 1, 2));
                        end
                    elseif train.currStation == "S2"
                        prev = app.UITable.Data(2*i, 4);
                        app.UITable.Data(2*i, 4) = realSchedule.S2ArrivalTime;
                        app.UITable.Data(2*i, 5) = realSchedule.S2LeaveTime;
                        if prev == -1
                            app.UITable.Data(2*i, 13) = app.UITable.Data(2*i, 13) + max(0, app.UITable.Data(2*i, 4) - app.UITable.Data(2*i - 1, 4));
                        end
                    elseif train.currStation == "S3"
                        prev = app.UITable.Data(2*i, 6);
                        app.UITable.Data(2*i, 6) = realSchedule.S3ArrivalTime;
                        app.UITable.Data(2*i, 7) = realSchedule.S3LeaveTime;
                        if prev == -1
                            app.UITable.Data(2*i, 13) = app.UITable.Data(2*i, 13) + max(0, app.UITable.Data(2*i, 6) - app.UITable.Data(2*i - 1, 6));
                        end
                    elseif train.currStation == "S4"
                        prev = app.UITable.Data(2*i, 8);
                        app.UITable.Data(2*i, 8) = realSchedule.S4ArrivalTime;
                        app.UITable.Data(2*i, 9) = realSchedule.S4LeaveTime;
                        if prev == -1
                            app.UITable.Data(2*i, 13) = app.UITable.Data(2*i, 13) + max(0, app.UITable.Data(2*i, 8) - app.UITable.Data(2*i - 1, 8));
                        end
                    elseif train.currStation == "S5"
                        prev = app.UITable.Data(2*i, 10);
                        app.UITable.Data(2*i, 10) = realSchedule.S5ArrivalTime;
                        app.UITable.Data(2*i, 11) = realSchedule.S5LeaveTime;
                        if prev == -1
                            app.UITable.Data(2*i, 13) = app.UITable.Data(2*i, 13) + max(0, app.UITable.Data(2*i, 10) - app.UITable.Data(2*i - 1, 10));
                        end
                    elseif train.currStation == "Termi"
                        prev = app.UITable.Data(2*i, 12);
                        app.UITable.Data(2*i, 12) = realSchedule.TermiArrivalTime;
                        if prev == -1
                            app.UITable.Data(2*i, 13) = app.UITable.Data(2*i, 13) + max(0, app.UITable.Data(2*i, 12) - app.UITable.Data(2*i - 1, 12));
                        end
                    end
                    train.updateScheduleFlag = 0;
                end
            end
        end
        
        function moveTrain(app, index, position)
            eval(['app.Train', num2str(index), 'Label.Position(1)=app.railwayStart+position*app.lengthPerMeter-app.Train', num2str(index), 'Label.Position(3)/2', ';']);
            eval(['app.Train', num2str(index), 'Image.Position(1)=app.railwayStart+position*app.lengthPerMeter-app.Train', num2str(index), 'Image.Position(3)/2', ';']);
        end
        
        function showRailState(app, index)
            station = app.hEnvironment.stations{index};
            currentInRailIndex = station.mainInRailIndex;
            currentOutRailIndex = station.mainOutRailIndex;
            eval(['app.S', num2str(index), 'In', num2str(currentInRailIndex), '.Value=1', ';']);
            eval(['app.S', num2str(index), 'Out', num2str(currentOutRailIndex), '.Value=1', ';']);
            inSwitchLock = station.inSwitchLock;
            outSwitchLock = station.outSwitchLock;
            if inSwitchLock == 0
                eval(['app.S', num2str(index), 'InSwitchLamp.Color = [1, 0, 0]', ';']);
            else
                eval(['app.S', num2str(index), 'InSwitchLamp.Color = [0.5, 0.5, 0.5]', ';']);
            end
            
            if outSwitchLock == 0
                eval(['app.S', num2str(index), 'OutSwitchLamp.Color = [1, 0, 0]', ';']);
            else
                eval(['app.S', num2str(index), 'OutSwitchLamp.Color = [0.5, 0.5, 0.5]', ';']);
            end
            
            for i=2:station.totalRailNum
                eval(['app.S', num2str(index), 'Rail', num2str(i-1), '.Value=station.rails{i}.currentTrainName', ';']);
            end
        end
    end
    
    methods (Access = public)
        
        function LoadRealSchedule(app)
            for n=1:app.hEnvironment.trainNum
                train = app.hEnvironment.trains(n);
                realSchedule = train.realSchedule;
                i = 2*n;
                app.UITable.Data(i, 1) = realSchedule.StartLeaveTime;
                app.UITable.Data(i, 2) = realSchedule.S1ArrivalTime;
                app.UITable.Data(i, 3) = realSchedule.S1LeaveTime;
                app.UITable.Data(i, 4) = realSchedule.S2ArrivalTime;
                app.UITable.Data(i, 5) = realSchedule.S2LeaveTime;
                app.UITable.Data(i, 6) = realSchedule.S3ArrivalTime;
                app.UITable.Data(i, 7) = realSchedule.S3LeaveTime;
                app.UITable.Data(i, 8) = realSchedule.S4ArrivalTime;
                app.UITable.Data(i, 9) = realSchedule.S4LeaveTime;
                app.UITable.Data(i, 10) = realSchedule.S5ArrivalTime;
                app.UITable.Data(i, 11) = realSchedule.S5LeaveTime;
                app.UITable.Data(i, 12) = realSchedule.TermiArrivalTime;
            end
        end
    end
    
    methods (Access = public)
        
        function computeDelay(app)
            for n=1:app.hEnvironment.trainNum
                sumDelay = 0;
                plan = 2*n-1;
                real = 2*n;
                for i=2:2:12
                    if app.UITable.Data(real, i) == -1 || app.UITable.Data(plan, i) == -1
                        continue
                    end
                    delay = max(app.UITable.Data(real, i) - app.UITable.Data(plan, i), 0);
                    sumDelay = sumDelay + delay;
                end
                app.UITable.Data(real, 13) = sumDelay;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.railwayStart = app.Railway.Position(1);
            app.railwayLength = app.Railway.Position(3);
            app.railwayEnd = app.railwayStart + app.railwayLength;
        end

        % Button pushed function: LoadInitialScheduleButton
        function LoadInitialScheduleButtonPushed(app, event)
            % Load schedule
            for n = 1:app.hEnvironment.trainNum
                i = 2*n-1;
                train = app.hEnvironment.trains(n);
                planSchedule = train.planSchedule;
                realSchedule = train.realSchedule;
                app.UITable.RowName{i} = [train.name, '-plan'];
                app.UITable.Data(i, 1) = planSchedule.StartLeaveTime;
                app.UITable.Data(i, 2) = planSchedule.S1ArrivalTime;
                app.UITable.Data(i, 3) = planSchedule.S1LeaveTime;
                app.UITable.Data(i, 4) = planSchedule.S2ArrivalTime;
                app.UITable.Data(i, 5) = planSchedule.S2LeaveTime;
                app.UITable.Data(i, 6) = planSchedule.S3ArrivalTime;
                app.UITable.Data(i, 7) = planSchedule.S3LeaveTime;
                app.UITable.Data(i, 8) = planSchedule.S4ArrivalTime;
                app.UITable.Data(i, 9) = planSchedule.S4LeaveTime;
                app.UITable.Data(i, 10) = planSchedule.S5ArrivalTime;
                app.UITable.Data(i, 11) = planSchedule.S5LeaveTime;
                app.UITable.Data(i, 12) = planSchedule.TermiArrivalTime;
                app.UITable.Data(i, 13) = 0;
                app.UITable.RowName{i+1} = [train.name, '-real'];
                app.UITable.Data(i+1, 1) = realSchedule.StartLeaveTime;
                app.UITable.Data(i+1, 2) = realSchedule.S1ArrivalTime;
                app.UITable.Data(i+1, 3) = realSchedule.S1LeaveTime;
                app.UITable.Data(i+1, 4) = realSchedule.S2ArrivalTime;
                app.UITable.Data(i+1, 5) = realSchedule.S2LeaveTime;
                app.UITable.Data(i+1, 6) = realSchedule.S3ArrivalTime;
                app.UITable.Data(i+1, 7) = realSchedule.S3LeaveTime;
                app.UITable.Data(i+1, 8) = realSchedule.S4ArrivalTime;
                app.UITable.Data(i+1, 9) = realSchedule.S4LeaveTime;
                app.UITable.Data(i+1, 10) = realSchedule.S5ArrivalTime;
                app.UITable.Data(i+1, 11) = realSchedule.S5LeaveTime;
                app.UITable.Data(i+1, 12) = realSchedule.TermiArrivalTime;
                app.UITable.Data(i+1, 13) = 0;
            end
            app.BeginSimulateButton.Enable = 'on';
            
            % Load layout
            app.lengthPerMeter = app.railwayLength / app.hEnvironment.railwayLength;
            app.StartLabel.Position(1) = app.railwayStart-app.StartLabel.Position(3)/2;
            app.StartImage.Position(1) = app.railwayStart-app.StartImage.Position(3)/2;
            app.TermiLabel.Position(1) = app.railwayEnd-app.TermiLabel.Position(3)/2;
            app.TermiImage.Position(1) = app.railwayEnd-app.TermiImage.Position(3)/2;
            app.StartLabel.Visible = 1;
            app.StartImage.Visible = 1;
            app.TermiLabel.Visible = 1;
            app.TermiImage.Visible = 1;
            % Set the position of each station including label and image
            for i=1:app.hEnvironment.stationNum
                eval(['app.S', num2str(i), 'Label.Position(1)=app.railwayStart+app.hEnvironment.layout(', num2str(i), ')*app.lengthPerMeter', '-app.S', num2str(i), 'Label.Position(3)/2', ';']);
                eval(['app.S', num2str(i), 'Image.Position(1)=app.railwayStart+app.hEnvironment.layout(', num2str(i), ')*app.lengthPerMeter', '-app.S', num2str(i), 'Image.Position(3)/2', ';']);
                eval(['app.S', num2str(i), 'Label.Visible=1', ';']);
                eval(['app.S', num2str(i), 'Image.Visible=1', ';']);
                for j=2:4
                    if j <= app.hEnvironment.railNums(i)
                        eval(['app.S', num2str(i), 'Rail', num2str(j-1), '.Value="None"', ';']);
                    end
                end
            end
            % Set the position of each train
            for i=1:app.hEnvironment.trainNum
                trainLabel = ['×', newline, app.hEnvironment.trains(i).name]; %#ok<NASGU> 
                eval(['app.Train', num2str(i), 'Label.Text=trainLabel', ';']);
                eval(['app.Train', num2str(i), 'Label.Position(1)=app.railwayStart-app.Train', num2str(i), 'Label.Position(3)/2', ';']);
                eval(['app.Train', num2str(i), 'Image.Position(1)=app.railwayStart-app.Train', num2str(i), 'Image.Position(3)/2', ';']);
                eval(['app.Train', num2str(i), 'Label.Visible=1', ';']);
                eval(['app.Train', num2str(i), 'Image.Visible=1', ';']);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: BeginSimulateButton
        function BeginSimulateButtonPushed(app, event)
            app.hSystem.beginSimulate();
            app.BeginSimulateButton.Enable = 'off';
            app.StopSimulateButton.Enable = 'on';
        end

        % Button pushed function: StopSimulateButton
        function StopSimulateButtonPushed(app, event)
            app.hSystem.stopSimulate();
            app.BeginSimulateButton.Enable = 'on';
            app.StopSimulateButton.Enable = 'off';
        end

        % Selection changed function: S1inButtonGroup
        function S1inButtonGroupSelectionChanged(app, event)
            selectedButton = app.S1inButtonGroup.SelectedObject;
            in = 1;
            selectedRailIndex = str2num(selectedButton.Text);
            station = app.hController.findStation("S1");
            app.hController.switchRail(station, selectedRailIndex, in);
        end

        % Selection changed function: S1outButtonGroup
        function S1outButtonGroupSelectionChanged(app, event)
            selectedButton = app.S1outButtonGroup.SelectedObject;
            in = 0;
            selectedRailIndex = str2num(selectedButton.Text);
            station = app.hController.findStation("S1");
            app.hController.switchRail(station, selectedRailIndex, in);
        end

        % Selection changed function: S2inButtonGroup
        function S2inButtonGroupSelectionChanged(app, event)
            selectedButton = app.S2inButtonGroup.SelectedObject;
            in = 1;
            selectedRailIndex = str2num(selectedButton.Text);
            station = app.hController.findStation("S2");
            app.hController.switchRail(station, selectedRailIndex, in);
        end

        % Selection changed function: S2outButtonGroup
        function S2outButtonGroupSelectionChanged(app, event)
            selectedButton = app.S2outButtonGroup.SelectedObject;
            in = 0;
            selectedRailIndex = str2num(selectedButton.Text);
            station = app.hController.findStation("S2");
            app.hController.switchRail(station, selectedRailIndex, in);
        end

        % Selection changed function: S3inButtonGroup
        function S3inButtonGroupSelectionChanged(app, event)
            selectedButton = app.S3inButtonGroup.SelectedObject;
            in = 1;
            selectedRailIndex = str2num(selectedButton.Text);
            station = app.hController.findStation("S3");
            app.hController.switchRail(station, selectedRailIndex, in);
        end

        % Selection changed function: S3outButtonGroup
        function S3outButtonGroupSelectionChanged(app, event)
            selectedButton = app.S3outButtonGroup.SelectedObject;
            in = 0;
            selectedRailIndex = str2num(selectedButton.Text);
            station = app.hController.findStation("S3");
            app.hController.switchRail(station, selectedRailIndex, in);
        end

        % Selection changed function: S4inButtonGroup
        function S4inButtonGroupSelectionChanged(app, event)
            selectedButton = app.S4inButtonGroup.SelectedObject;
            in = 1;
            selectedRailIndex = str2num(selectedButton.Text);
            station = app.hController.findStation("S4");
            app.hController.switchRail(station, selectedRailIndex, in);
        end

        % Selection changed function: S4outButtonGroup
        function S4outButtonGroupSelectionChanged(app, event)
            selectedButton = app.S4outButtonGroup.SelectedObject;
            in = 0;
            selectedRailIndex = str2num(selectedButton.Text);
            station = app.hController.findStation("S4");
            app.hController.switchRail(station, selectedRailIndex, in);
        end

        % Selection changed function: S5inButtonGroup
        function S5inButtonGroupSelectionChanged(app, event)
            selectedButton = app.S5inButtonGroup.SelectedObject;
            in = 1;
            selectedRailIndex = str2num(selectedButton.Text);
            station = app.hController.findStation("S5");
            app.hController.switchRail(station, selectedRailIndex, in);
        end

        % Selection changed function: S5outButtonGroup
        function S5outButtonGroupSelectionChanged(app, event)
            selectedButton = app.S5outButtonGroup.SelectedObject;
            in = 0;
            selectedRailIndex = str2num(selectedButton.Text);
            station = app.hController.findStation("S5");
            app.hController.switchRail(station, selectedRailIndex, in);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SimulatorPanel = uipanel(app.UIFigure);
            app.SimulatorPanel.Title = 'Simulator Panel';
            app.Railway = uilabel(app.SimulatorPanel);
            app.Railway.Text = '---------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.TimeEditFieldLabel = uilabel(app.SimulatorPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.SimulatorPanel, 'numeric');
            app.TimeEditField.Editable = 'off';
            app.StartLabel = uilabel(app.SimulatorPanel);
            app.StartLabel.Visible = 'off';
            app.StartLabel.Text = 'Start';
            app.S1Label = uilabel(app.SimulatorPanel);
            app.S1Label.Visible = 'off';
            app.S1Label.Text = 'S1';
            app.S2Label = uilabel(app.SimulatorPanel);
            app.S2Label.Visible = 'off';
            app.S2Label.Text = 'S2';
            app.S3Label = uilabel(app.SimulatorPanel);
            app.S3Label.Visible = 'off';
            app.S3Label.Text = 'S3';
            app.S4Label = uilabel(app.SimulatorPanel);
            app.S4Label.Visible = 'off';
            app.S4Label.Text = 'S4';
            app.S5Label = uilabel(app.SimulatorPanel);
            app.S5Label.Visible = 'off';
            app.S5Label.Text = 'S5';
            app.TermiLabel = uilabel(app.SimulatorPanel);
            app.TermiLabel.Visible = 'off';
            app.TermiLabel.Text = 'Terminal';
            app.S1Image = uiimage(app.SimulatorPanel);
            app.S1Image.Visible = 'off';
            app.S1Image.ImageSource = 'station.jpg';
            app.S2Image = uiimage(app.SimulatorPanel);
            app.S2Image.Visible = 'off';
            app.S2Image.ImageSource = 'station.jpg';
            app.S3Image = uiimage(app.SimulatorPanel);
            app.S3Image.Visible = 'off';
            app.S3Image.ImageSource = 'station.jpg';
            app.S4Image = uiimage(app.SimulatorPanel);
            app.S4Image.Visible = 'off';
            app.S4Image.ImageSource = 'station.jpg';
            app.S5Image = uiimage(app.SimulatorPanel);
            app.S5Image.Visible = 'off';
            app.S5Image.ImageSource = 'station.jpg';
            app.StartImage = uiimage(app.SimulatorPanel);
            app.StartImage.Visible = 'off';
            app.StartImage.ImageSource = 'start.jpg';
            app.TermiImage = uiimage(app.SimulatorPanel);
            app.TermiImage.Visible = 'off';
            app.TermiImage.ImageSource = 'start.jpg';
            app.Train1Image = uiimage(app.SimulatorPanel);
            app.Train1Image.Visible = 'off';
            app.Train1Image.ImageSource = 'train.jpg';
            app.Train1Label = uilabel(app.SimulatorPanel);
            app.Train1Label.Visible = 'off';
            app.Train1Label.Text = {'×'; 'Train1'};
            app.Train2Image = uiimage(app.SimulatorPanel);
            app.Train2Image.Visible = 'off';
            app.Train2Image.ImageSource = 'train.jpg';
            app.Train2Label = uilabel(app.SimulatorPanel);
            app.Train2Label.Visible = 'off';
            app.Train2Label.Text = {'×'; 'Train2'};
            app.Train3Image = uiimage(app.SimulatorPanel);
            app.Train3Image.Visible = 'off';
            app.Train3Image.ImageSource = 'train.jpg';
            app.Train3Label = uilabel(app.SimulatorPanel);
            app.Train3Label.Visible = 'off';
            app.Train3Label.Text = {'×'; 'Train3'};
            app.Train4Image = uiimage(app.SimulatorPanel);
            app.Train4Image.Visible = 'off';
            app.Train4Image.ImageSource = 'train.jpg';
            app.Train4Label = uilabel(app.SimulatorPanel);
            app.Train4Label.Visible = 'off';
            app.Train4Label.Text = {'×'; 'Train4'};
            app.Train5Image = uiimage(app.SimulatorPanel);
            app.Train5Image.Visible = 'off';
            app.Train5Image.ImageSource = 'train.jpg';
            app.Train5Label = uilabel(app.SimulatorPanel);
            app.Train5Label.Visible = 'off';
            app.Train5Label.Text = {'×'; 'Train5'};
            app.S1Panel = uipanel(app.SimulatorPanel);
            app.S1Panel.Title = 'S1';
            app.S1inButtonGroup = uibuttongroup(app.S1Panel);
            app.S1inButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @S1inButtonGroupSelectionChanged, true);
            app.S1inButtonGroup.Title = 'S1-in';
            app.S1In0 = uitogglebutton(app.S1inButtonGroup);
            app.S1In0.Text = '0';
            app.S1In0.Value = true;
            app.S1In1 = uitogglebutton(app.S1inButtonGroup);
            app.S1In1.Text = '1';
            app.switchLampLabel = uilabel(app.S1inButtonGroup);
            app.switchLampLabel.Text = 'switch';
            app.S1InSwitchLamp = uilamp(app.S1inButtonGroup);
            app.S1In2 = uitogglebutton(app.S1inButtonGroup);
            app.S1In2.Text = '2';
            app.S1In3 = uitogglebutton(app.S1inButtonGroup);
            app.S1In3.Enable = 'off';
            app.S1In3.Text = '3';
            app.S1outButtonGroup = uibuttongroup(app.S1Panel);
            app.S1outButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @S1outButtonGroupSelectionChanged, true);
            app.S1outButtonGroup.Title = 'S1-out';
            app.S1Out0 = uitogglebutton(app.S1outButtonGroup);
            app.S1Out0.Text = '0';
            app.S1Out0.Value = true;
            app.S1Out1 = uitogglebutton(app.S1outButtonGroup);
            app.S1Out1.Text = '1';
            app.switchLamp_2Label = uilabel(app.S1outButtonGroup);
            app.switchLamp_2Label.Text = 'switch';
            app.S1OutSwitchLamp = uilamp(app.S1outButtonGroup);
            app.S1Out2 = uitogglebutton(app.S1outButtonGroup);
            app.S1Out2.Text = '2';
            app.S1Out3 = uitogglebutton(app.S1outButtonGroup);
            app.S1Out3.Enable = 'off';
            app.S1Out3.Text = '3';
            app.Rail1EditFieldLabel = uilabel(app.S1Panel);
            app.Rail1EditFieldLabel.Text = 'Rail 1';
            app.S1Rail1 = uieditfield(app.S1Panel, 'text');
            app.Rail2EditFieldLabel = uilabel(app.S1Panel);
            app.Rail2EditFieldLabel.Text = 'Rail 2';
            app.S1Rail2 = uieditfield(app.S1Panel, 'text');
            app.Rail3EditFieldLabel = uilabel(app.S1Panel);
            app.Rail3EditFieldLabel.Enable = 'off';
            app.Rail3EditFieldLabel.Text = 'Rail 3';
            app.S1Rail3 = uieditfield(app.S1Panel, 'text');
            app.S1Rail3.Enable = 'off';
            app.S2Panel = uipanel(app.SimulatorPanel);
            app.S2Panel.Title = 'S2';
            app.Rail1EditField_2Label = uilabel(app.S2Panel);
            app.Rail1EditField_2Label.Text = 'Rail 1';
            app.S2Rail1 = uieditfield(app.S2Panel, 'text');
            app.Rail2EditField_2Label = uilabel(app.S2Panel);
            app.Rail2EditField_2Label.Enable = 'off';
            app.Rail2EditField_2Label.Text = 'Rail 2';
            app.S2Rail2 = uieditfield(app.S2Panel, 'text');
            app.S2Rail2.Enable = 'off';
            app.Rail3EditField_2Label = uilabel(app.S2Panel);
            app.Rail3EditField_2Label.Enable = 'off';
            app.Rail3EditField_2Label.Text = 'Rail 3';
            app.S2Rail3 = uieditfield(app.S2Panel, 'text');
            app.S2Rail3.Enable = 'off';
            app.S2inButtonGroup = uibuttongroup(app.S2Panel);
            app.S2inButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @S2inButtonGroupSelectionChanged, true);
            app.S2inButtonGroup.Title = 'S2-in';
            app.S2In0 = uitogglebutton(app.S2inButtonGroup);
            app.S2In0.Text = '0';
            app.S2In0.Value = true;
            app.switchLamp_3Label = uilabel(app.S2inButtonGroup);
            app.switchLamp_3Label.Text = 'switch';
            app.S2InSwitchLamp = uilamp(app.S2inButtonGroup);
            app.S2In1 = uitogglebutton(app.S2inButtonGroup);
            app.S2In1.Text = '1';
            app.S2In2 = uitogglebutton(app.S2inButtonGroup);
            app.S2In2.Enable = 'off';
            app.S2In2.Text = '2';
            app.S2In3 = uitogglebutton(app.S2inButtonGroup);
            app.S2In3.Enable = 'off';
            app.S2In3.Text = '3';
            app.S2outButtonGroup = uibuttongroup(app.S2Panel);
            app.S2outButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @S2outButtonGroupSelectionChanged, true);
            app.S2outButtonGroup.Title = 'S2-out';
            app.S2Out0 = uitogglebutton(app.S2outButtonGroup);
            app.S2Out0.Text = '0';
            app.S2Out0.Value = true;
            app.switchLamp_4Label = uilabel(app.S2outButtonGroup);
            app.switchLamp_4Label.Text = 'switch';
            app.S2OutSwitchLamp = uilamp(app.S2outButtonGroup);
            app.S2Out1 = uitogglebutton(app.S2outButtonGroup);
            app.S2Out1.Text = '1';
            app.S2Out2 = uitogglebutton(app.S2outButtonGroup);
            app.S2Out2.Enable = 'off';
            app.S2Out2.Text = '2';
            app.S2Out3 = uitogglebutton(app.S2outButtonGroup);
            app.S2Out3.Enable = 'off';
            app.S2Out3.Text = '3';
            app.S3Panel = uipanel(app.SimulatorPanel);
            app.S3Panel.Title = 'S3';
            app.Rail1EditField_3Label = uilabel(app.S3Panel);
            app.Rail1EditField_3Label.Text = 'Rail 1';
            app.S3Rail1 = uieditfield(app.S3Panel, 'text');
            app.Rail2EditField_3Label = uilabel(app.S3Panel);
            app.Rail2EditField_3Label.Text = 'Rail 2';
            app.S3Rail2 = uieditfield(app.S3Panel, 'text');
            app.Rail3EditField_3Label = uilabel(app.S3Panel);
            app.Rail3EditField_3Label.Text = 'Rail 3';
            app.S3Rail3 = uieditfield(app.S3Panel, 'text');
            app.S3inButtonGroup = uibuttongroup(app.S3Panel);
            app.S3inButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @S3inButtonGroupSelectionChanged, true);
            app.S3inButtonGroup.Title = 'S3-in';
            app.S3In0 = uitogglebutton(app.S3inButtonGroup);
            app.S3In0.Text = '0';
            app.S3In0.Value = true;
            app.S3In1 = uitogglebutton(app.S3inButtonGroup);
            app.S3In1.Text = '1';
            app.S3In2 = uitogglebutton(app.S3inButtonGroup);
            app.S3In2.Text = '2';
            app.switchLamp_5Label = uilabel(app.S3inButtonGroup);
            app.switchLamp_5Label.Text = 'switch';
            app.S3InSwitchLamp = uilamp(app.S3inButtonGroup);
            app.S3In3 = uitogglebutton(app.S3inButtonGroup);
            app.S3In3.Text = '3';
            app.S3outButtonGroup = uibuttongroup(app.S3Panel);
            app.S3outButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @S3outButtonGroupSelectionChanged, true);
            app.S3outButtonGroup.Title = 'S3-out';
            app.S3Out0 = uitogglebutton(app.S3outButtonGroup);
            app.S3Out0.Text = '0';
            app.S3Out0.Value = true;
            app.S3Out1 = uitogglebutton(app.S3outButtonGroup);
            app.S3Out1.Text = '1';
            app.S3Out2 = uitogglebutton(app.S3outButtonGroup);
            app.S3Out2.Text = '2';
            app.switchLamp_6Label = uilabel(app.S3outButtonGroup);
            app.switchLamp_6Label.Text = 'switch';
            app.S3OutSwitchLamp = uilamp(app.S3outButtonGroup);
            app.S3Out3 = uitogglebutton(app.S3outButtonGroup);
            app.S3Out3.Text = '3';
            app.S4Panel = uipanel(app.SimulatorPanel);
            app.S4Panel.Title = 'S4';
            app.Rail1EditField_4Label = uilabel(app.S4Panel);
            app.Rail1EditField_4Label.Text = 'Rail 1';
            app.S4Rail1 = uieditfield(app.S4Panel, 'text');
            app.Rail2EditField_4Label = uilabel(app.S4Panel);
            app.Rail2EditField_4Label.Enable = 'off';
            app.Rail2EditField_4Label.Text = 'Rail 2';
            app.S4Rail2 = uieditfield(app.S4Panel, 'text');
            app.S4Rail2.Enable = 'off';
            app.Rail3EditField_4Label = uilabel(app.S4Panel);
            app.Rail3EditField_4Label.Enable = 'off';
            app.Rail3EditField_4Label.Text = 'Rail 3';
            app.S4Rail3 = uieditfield(app.S4Panel, 'text');
            app.S4Rail3.Enable = 'off';
            app.S4inButtonGroup = uibuttongroup(app.S4Panel);
            app.S4inButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @S4inButtonGroupSelectionChanged, true);
            app.S4inButtonGroup.Title = 'S4-in';
            app.S4In0 = uitogglebutton(app.S4inButtonGroup);
            app.S4In0.Text = '0';
            app.S4In0.Value = true;
            app.switchLamp_7Label = uilabel(app.S4inButtonGroup);
            app.switchLamp_7Label.Text = 'switch';
            app.S4InSwitchLamp = uilamp(app.S4inButtonGroup);
            app.S4In1 = uitogglebutton(app.S4inButtonGroup);
            app.S4In1.Text = '1';
            app.S4In2 = uitogglebutton(app.S4inButtonGroup);
            app.S4In2.Enable = 'off';
            app.S4In2.Text = '2';
            app.S4In3 = uitogglebutton(app.S4inButtonGroup);
            app.S4In3.Enable = 'off';
            app.S4In3.Text = '3';
            app.S4outButtonGroup = uibuttongroup(app.S4Panel);
            app.S4outButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @S4outButtonGroupSelectionChanged, true);
            app.S4outButtonGroup.Title = 'S4-out';
            app.S4Out0 = uitogglebutton(app.S4outButtonGroup);
            app.S4Out0.Text = '0';
            app.S4Out0.Value = true;
            app.switchLamp_8Label = uilabel(app.S4outButtonGroup);
            app.switchLamp_8Label.Text = 'switch';
            app.S4OutSwitchLamp = uilamp(app.S4outButtonGroup);
            app.S4Out1 = uitogglebutton(app.S4outButtonGroup);
            app.S4Out1.Text = '1';
            app.S4Out2 = uitogglebutton(app.S4outButtonGroup);
            app.S4Out2.Enable = 'off';
            app.S4Out2.Text = '2';
            app.S4Out3 = uitogglebutton(app.S4outButtonGroup);
            app.S4Out3.Enable = 'off';
            app.S4Out3.Text = '3';
            app.S5Panel = uipanel(app.SimulatorPanel);
            app.S5Panel.Title = 'S5';
            app.Rail1EditField_5Label = uilabel(app.S5Panel);
            app.Rail1EditField_5Label.Text = 'Rail 1';
            app.S5Rail1 = uieditfield(app.S5Panel, 'text');
            app.Rail2EditField_5Label = uilabel(app.S5Panel);
            app.Rail2EditField_5Label.Text = 'Rail 2';
            app.S5Rail2 = uieditfield(app.S5Panel, 'text');
            app.Rail3EditField_5Label = uilabel(app.S5Panel);
            app.Rail3EditField_5Label.Text = 'Rail 3';
            app.S5Rail3 = uieditfield(app.S5Panel, 'text');
            app.S5inButtonGroup = uibuttongroup(app.S5Panel);
            app.S5inButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @S5inButtonGroupSelectionChanged, true);
            app.S5inButtonGroup.Title = 'S5-in';
            app.S5In0 = uitogglebutton(app.S5inButtonGroup);
            app.S5In0.Text = '0';
            app.S5In0.Value = true;
            app.S5In1 = uitogglebutton(app.S5inButtonGroup);
            app.S5In1.Text = '1';
            app.S5In2 = uitogglebutton(app.S5inButtonGroup);
            app.S5In2.Text = '2';
            app.switchLamp_9Label = uilabel(app.S5inButtonGroup);
            app.switchLamp_9Label.Text = 'switch';
            app.S5InSwitchLamp = uilamp(app.S5inButtonGroup);
            app.S5In3 = uitogglebutton(app.S5inButtonGroup);
            app.S5In3.Text = '3';
            app.S5outButtonGroup = uibuttongroup(app.S5Panel);
            app.S5outButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @S5outButtonGroupSelectionChanged, true);
            app.S5outButtonGroup.Title = 'S5-out';
            app.S5Out0 = uitogglebutton(app.S5outButtonGroup);
            app.S5Out0.Text = '0';
            app.S5Out0.Value = true;
            app.S5Out1 = uitogglebutton(app.S5outButtonGroup);
            app.S5Out1.Text = '1';
            app.S5Out2 = uitogglebutton(app.S5outButtonGroup);
            app.S5Out2.Text = '2';
            app.switchLamp_10Label = uilabel(app.S5outButtonGroup);
            app.switchLamp_10Label.Text = 'switch';
            app.S5OutSwitchLamp = uilamp(app.S5outButtonGroup);
            app.S5Out3 = uitogglebutton(app.S5outButtonGroup);
            app.S5Out3.Text = '3';
            app.Train6Label = uilabel(app.SimulatorPanel);
            app.Train6Label.Visible = 'off';
            app.Train6Label.Text = {'×'; 'Train6'};
            app.Train6Image = uiimage(app.SimulatorPanel);
            app.Train6Image.Visible = 'off';
            app.Train6Image.ImageSource = 'train.jpg';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.LoadInitialScheduleButton = uibutton(app.ControlPanel, 'push');
            app.LoadInitialScheduleButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInitialScheduleButtonPushed, true);
            app.LoadInitialScheduleButton.Text = 'Load Initial Schedule';
            app.BeginSimulateButton = uibutton(app.ControlPanel, 'push');
            app.BeginSimulateButton.ButtonPushedFcn = createCallbackFcn(app, @BeginSimulateButtonPushed, true);
            app.BeginSimulateButton.Enable = 'off';
            app.BeginSimulateButton.Text = 'Begin Simulate';
            app.StopSimulateButton = uibutton(app.ControlPanel, 'push');
            app.StopSimulateButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimulateButtonPushed, true);
            app.StopSimulateButton.Enable = 'off';
            app.StopSimulateButton.Text = 'Stop Simulate';
            app.SchedulePanel = uipanel(app.UIFigure);
            app.SchedulePanel.Title = 'Schedule Panel';
            app.UITable = uitable(app.SchedulePanel);
            app.UITable.ColumnName = {'start'; 'S1-in'; 'S1-out'; 'S2-in'; 'S2-out'; 'S3-in'; 'S3-out'; 'S4-in'; 'S4-out'; 'S5-in'; 'S5-out'; 'terminal'; 'delay'};
            app.UITable.RowName = {''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Display

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end