classdef OfflineMaintainPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        panel                         matlab.ui.container.Panel
        CellsPanel                    matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Lamp1                         matlab.ui.control.Lamp
        Lamp2                         matlab.ui.control.Lamp
        Lamp3                         matlab.ui.control.Lamp
        Lamp4                         matlab.ui.control.Lamp
        Image                         matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        Image3                        matlab.ui.control.Image
        Image4                        matlab.ui.control.Image
        FillButton                    matlab.ui.control.Button
        FetchButton                   matlab.ui.control.Button
        FillButton_2                  matlab.ui.control.Button
        FetchButton_2                 matlab.ui.control.Button
        FillButton_3                  matlab.ui.control.Button
        FetchButton_3                 matlab.ui.control.Button
        FillButton_4                  matlab.ui.control.Button
        FetchButton_4                 matlab.ui.control.Button
        PriceEditField_4Label_2       matlab.ui.control.Label
        PriceEditField_4              matlab.ui.control.NumericEditField
        PriceEditField_5Label         matlab.ui.control.Label
        PriceEditField_5              matlab.ui.control.NumericEditField
        PriceEditField_6Label         matlab.ui.control.Label
        PriceEditField_6              matlab.ui.control.NumericEditField
        PriceEditField_7Label         matlab.ui.control.Label
        PriceEditField_7              matlab.ui.control.NumericEditField
        InventoryEditFieldLabel       matlab.ui.control.Label
        inventory1                    matlab.ui.control.NumericEditField
        InventoryEditFieldLabel_2     matlab.ui.control.Label
        inventory2                    matlab.ui.control.NumericEditField
        InventoryEditFieldLabel_3     matlab.ui.control.Label
        inventory3                    matlab.ui.control.NumericEditField
        InventoryEditFieldLabel_4     matlab.ui.control.Label
        inventory4                    matlab.ui.control.NumericEditField
        ReplenishEditField_9Label     matlab.ui.control.Label
        Replenish1                    matlab.ui.control.NumericEditField
        ReplenishEditField_10Label    matlab.ui.control.Label
        Replenish2                    matlab.ui.control.NumericEditField
        ReplenishEditField_10Label_2  matlab.ui.control.Label
        Replenish3                    matlab.ui.control.NumericEditField
        ReplenishEditField_10Label_3  matlab.ui.control.Label
        Replenish4                    matlab.ui.control.NumericEditField
        WithdrawLabel                 matlab.ui.control.Label
        Withdraw1                     matlab.ui.control.NumericEditField
        WithdrawLabel_2               matlab.ui.control.Label
        Withdraw2                     matlab.ui.control.NumericEditField
        WithdrawLabel_3               matlab.ui.control.Label
        Withdraw3                     matlab.ui.control.NumericEditField
        WithdrawLabel_4               matlab.ui.control.Label
        Withdraw4                     matlab.ui.control.NumericEditField
        MoneyContainerPanel           matlab.ui.container.Panel
        Cash1rLabel                   matlab.ui.control.Label
        Cash5rLabel                   matlab.ui.control.Label
        CoinHalfrLabel                matlab.ui.control.Label
        Coin1rLabel                   matlab.ui.control.Label
        AmountEditFieldLabel          matlab.ui.control.Label
        AmountEditField               matlab.ui.control.NumericEditField
        FillButton_5                  matlab.ui.control.Button
        FetchButton_5                 matlab.ui.control.Button
        FillButton_6                  matlab.ui.control.Button
        FetchButton_6                 matlab.ui.control.Button
        AmountEditField_2Label        matlab.ui.control.Label
        AmountEditField_2             matlab.ui.control.NumericEditField
        FillButton_7                  matlab.ui.control.Button
        FetchButton_7                 matlab.ui.control.Button
        AmountEditField_3Label        matlab.ui.control.Label
        AmountEditField_3             matlab.ui.control.NumericEditField
        FillButton_8                  matlab.ui.control.Button
        FetchButton_8                 matlab.ui.control.Button
        AmountEditField_4Label        matlab.ui.control.Label
        AmountEditField_4             matlab.ui.control.NumericEditField
        MoneyLamp3                    matlab.ui.control.Lamp
        MoneyLamp4                    matlab.ui.control.Lamp
        MoneyLamp2                    matlab.ui.control.Lamp
        MoneyLamp1                    matlab.ui.control.Lamp
        Replenish1_2                  matlab.ui.control.NumericEditField
        Replenish2_2                  matlab.ui.control.NumericEditField
        Replenish3_2                  matlab.ui.control.NumericEditField
        Replenish4_2                  matlab.ui.control.NumericEditField
        Withdraw1_2                   matlab.ui.control.NumericEditField
        Withdraw2_2                   matlab.ui.control.NumericEditField
        Withdraw3_2                   matlab.ui.control.NumericEditField
        Withdraw4_2                   matlab.ui.control.NumericEditField
        ReplenishEditField_9Label_2   matlab.ui.control.Label
        ReplenishEditField_10Label_4  matlab.ui.control.Label
        ReplenishEditField_10Label_5  matlab.ui.control.Label
        ReplenishEditField_10Label_6  matlab.ui.control.Label
        WithdrawLabel_5               matlab.ui.control.Label
        WithdrawLabel_6               matlab.ui.control.Label
        WithdrawLabel_7               matlab.ui.control.Label
        WithdrawLabel_8               matlab.ui.control.Label
        LampColorIndicationButton     matlab.ui.control.Button
        FixButton                     matlab.ui.control.Button
        UserproblemLampLabel          matlab.ui.control.Label
        UserproblemLamp               matlab.ui.control.Lamp
        OfflineMaintainPanelLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        vending % Description
    end
    
   methods (Access = public)
        function color = inventoryLightIndicator(app, inventory)
           if inventory ==  0
                           % branch T cover 1.7.1.1
                   color = [1 0 0];
           elseif inventory < 5 && inventory > 0
                           % branch T cover 1.7.1.2
               color = [1 1 0.07];
           elseif inventory >= 5 && inventory <= 95
                           % branch T cover 1.7.1.3
               color = [0 1 0];
           elseif inventory > 95 && inventory < 100
                           % branch T cover 1.7.1.4
               color = [0 0 1];
           elseif inventory == 100
                           % branch T cover 1.7.1.5
               color = [0 0 0];
           else
                           % branch T cover 1.7.1.6
               % should not happen
               color = [1 1 1];
           end
        end
        
        function color = moneyLightIndicator(app, amount)
           if amount ==  0
               % branch T cover 1.7.2.1
                   color = [1 0 0];
           elseif amount < 50 && amount > 0
               % branch T cover 1.7.2.2
               color = [1 1 0.07];
           elseif amount >= 50 && amount <= 950
               % branch T cover 1.7.2.3
               color = [0 1 0];
           elseif amount > 950 && amount < 1000
               % branch T cover 1.7.2.4
               color = [0 0 1];
           elseif amount == 1000
               % branch T cover 1.7.2.5
               color = [0 0 0];
           else
               % branch T cover 1.7.2.6
               % should not happen
               color = [1 1 1];
           end
        end
        
        % lamp indication
        function updateCellLamp(app, inventory1, inventory2, inventory3, inventory4)
            % statement t cover 1.7.3.1
            %i = [inventory1, inventory2, inventory3, inventory4];
            app.inventory1.Value = inventory1;
            app.inventory2.Value = inventory2;
            app.inventory3.Value = inventory3;
            app.inventory4.Value = inventory4;

            app.Lamp1.Color = app.inventoryLightIndicator(inventory1);
            app.Lamp2.Color = app.inventoryLightIndicator(inventory2);
            app.Lamp3.Color = app.inventoryLightIndicator(inventory3);
            app.Lamp4.Color = app.inventoryLightIndicator(inventory4);
        end
   
        % lamp indication
        function updateMoneyLamp(app, cash1r, cash5r, coinHalfr, coin1r)
             % statement t cover 1.7.4.1
            %m = [cash1r, cash5r, coinHalfr, coin1r];
            app.AmountEditField.Value = cash1r;
            app.AmountEditField_2.Value = cash5r;
            app.AmountEditField_3.Value = coinHalfr;
            app.AmountEditField_4.Value = coin1r;
            
            app.MoneyLamp1.Color = app.moneyLightIndicator(cash1r);
            app.MoneyLamp2.Color = app.moneyLightIndicator(cash5r);
            app.MoneyLamp3.Color = app.moneyLightIndicator(coinHalfr);
            app.MoneyLamp4.Color = app.moneyLightIndicator(coin1r);
        end
        
   end

   methods (Access = private)
        function deltaCell(app, cellID, sign)
           if sign == 1
               % branch T cover 1.7.5.1
               name = "app.Replenish"+string(cellID)+".Value";
           else
               % branch T cover 1.7.5.2
               name = "app.Withdraw"+string(cellID)+".Value";
           end
           
           status = app.vending.cellsSys.updateInventory(cellID, eval(string(sign)+"*"+name));
           if status == 1
               % branch T cover 1.7.5.3
               eval(name + "=0");
               app.vending.updateOfflineMaintainPanel();
               app.vending.updateOnlineMaintainPanel(-1); 
               app.vending.updateUserPanelPhysically();
           end    
        end
        
        function deltaMoney(app, moneyID, sign)
            if sign == 1
                % branch T cover 1.7.6.1
                name = "app.Replenish"+string(moneyID)+"_2.Value";
            else %sign == -1
                % branch T cover 1.7.6.2
                name = "app.Withdraw"+string(moneyID)+"_2.Value";
            end
            
            if moneyID == 1
                % branch T cover 1.7.6.3
                status = app.vending.moneyProcessor.updateContainer(eval(string(sign)+"*"+name),0,0,0);
            elseif moneyID == 2
                % branch T cover 1.7.6.4
                status = app.vending.moneyProcessor.updateContainer(0,eval(string(sign)+"*"+name),0,0);
            elseif moneyID == 3
                % branch T cover 1.7.6.5
                status = app.vending.moneyProcessor.updateContainer(0,0,eval(string(sign)+"*"+name),0);
            else %moneyID == 4
                % branch T cover 1.7.6.6
                status = app.vending.moneyProcessor.updateContainer(0,0,0,eval(string(sign)+"*"+name));
            end
            
            if status(moneyID) == 1
                % branch T cover 1.7.6.7  
                eval(name + "=0");
                app.vending.updateOfflineMaintainPanel();
                app.vending.updateOnlineMaintainPanel(-1); 
                app.vending.updateUserPanelPhysically();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LampColorIndicationButton
        function LampColorIndicationButtonPushed(app, event)
            ColorIndicatorPanel();
        end

        % Button pushed function: FillButton
        function FillButtonPushed(app, event)
            app.deltaCell(1,1);
        end

        % Button pushed function: FillButton_2
        function FillButton_2Pushed(app, event)
            app.deltaCell(2,1);
        end

        % Button pushed function: FillButton_3
        function FillButton_3Pushed(app, event)
            app.deltaCell(3,1);
        end

        % Button pushed function: FillButton_4
        function FillButton_4Pushed(app, event)
            app.deltaCell(4,1);
        end

        % Button pushed function: FillButton_5
        function FillButton_5Pushed(app, event)
            app.deltaMoney(1,1);
        end

        % Button pushed function: FillButton_6
        function FillButton_6Pushed(app, event)
            app.deltaMoney(2,1);
        end

        % Button pushed function: FillButton_7
        function FillButton_7Pushed(app, event)
            app.deltaMoney(3,1);
        end

        % Button pushed function: FillButton_8
        function FillButton_8Pushed(app, event)
            app.deltaMoney(4,1);
        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
            app.deltaCell(1, -1);
        end

        % Button pushed function: FetchButton_2
        function FetchButton_2Pushed(app, event)
            app.deltaCell(2, -1);
        end

        % Button pushed function: FetchButton_3
        function FetchButton_3Pushed(app, event)
            app.deltaCell(3, -1);
        end

        % Button pushed function: FetchButton_4
        function FetchButton_4Pushed(app, event)
            app.deltaCell(4, -1);
        end

        % Button pushed function: FetchButton_5
        function FetchButton_5Pushed(app, event)
            app.deltaMoney(1, -1)
        end

        % Button pushed function: FetchButton_6
        function FetchButton_6Pushed(app, event)
            app.deltaMoney(2, -1)
        end

        % Button pushed function: FetchButton_7
        function FetchButton_7Pushed(app, event)
            app.deltaMoney(3, -1)
        end

        % Button pushed function: FetchButton_8
        function FetchButton_8Pushed(app, event)
            app.deltaMoney(4, -1)
        end

        % Button pushed function: FixButton
        function FixButtonPushed(app, event)
            app.vending.fixUserProblem();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.panel = uipanel(app.UIFigure);
            app.panel.Title = 'panel';
            app.CellsPanel = uipanel(app.panel);
            app.CellsPanel.Title = 'Cells';
            app.Label = uilabel(app.CellsPanel);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.CellsPanel);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.CellsPanel);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.CellsPanel);
            app.Label_4.Text = '4';
            app.Lamp1 = uilamp(app.CellsPanel);
            app.Lamp2 = uilamp(app.CellsPanel);
            app.Lamp3 = uilamp(app.CellsPanel);
            app.Lamp4 = uilamp(app.CellsPanel);
            app.Image = uiimage(app.CellsPanel);
            app.Image.ImageSource = 'cola.png';
            app.Image2 = uiimage(app.CellsPanel);
            app.Image2.ImageSource = 'OIP.jpg';
            app.Image3 = uiimage(app.CellsPanel);
            app.Image3.ImageSource = '506975_1_pic500_3755.jpg';
            app.Image4 = uiimage(app.CellsPanel);
            app.Image4.ImageSource = '巧克力.jpg';
            app.FillButton = uibutton(app.CellsPanel, 'push');
            app.FillButton.ButtonPushedFcn = createCallbackFcn(app, @FillButtonPushed, true);
            app.FillButton.Text = 'Fill';
            app.FetchButton = uibutton(app.CellsPanel, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.FillButton_2 = uibutton(app.CellsPanel, 'push');
            app.FillButton_2.ButtonPushedFcn = createCallbackFcn(app, @FillButton_2Pushed, true);
            app.FillButton_2.Text = 'Fill';
            app.FetchButton_2 = uibutton(app.CellsPanel, 'push');
            app.FetchButton_2.ButtonPushedFcn = createCallbackFcn(app, @FetchButton_2Pushed, true);
            app.FetchButton_2.Text = 'Fetch';
            app.FillButton_3 = uibutton(app.CellsPanel, 'push');
            app.FillButton_3.ButtonPushedFcn = createCallbackFcn(app, @FillButton_3Pushed, true);
            app.FillButton_3.Text = 'Fill';
            app.FetchButton_3 = uibutton(app.CellsPanel, 'push');
            app.FetchButton_3.ButtonPushedFcn = createCallbackFcn(app, @FetchButton_3Pushed, true);
            app.FetchButton_3.Text = 'Fetch';
            app.FillButton_4 = uibutton(app.CellsPanel, 'push');
            app.FillButton_4.ButtonPushedFcn = createCallbackFcn(app, @FillButton_4Pushed, true);
            app.FillButton_4.Text = 'Fill';
            app.FetchButton_4 = uibutton(app.CellsPanel, 'push');
            app.FetchButton_4.ButtonPushedFcn = createCallbackFcn(app, @FetchButton_4Pushed, true);
            app.FetchButton_4.Text = 'Fetch';
            app.PriceEditField_4Label_2 = uilabel(app.CellsPanel);
            app.PriceEditField_4Label_2.Text = 'Price';
            app.PriceEditField_4 = uieditfield(app.CellsPanel, 'numeric');
            app.PriceEditField_4.Editable = 'off';
            app.PriceEditField_4.Value = 2.5;
            app.PriceEditField_5Label = uilabel(app.CellsPanel);
            app.PriceEditField_5Label.Text = 'Price';
            app.PriceEditField_5 = uieditfield(app.CellsPanel, 'numeric');
            app.PriceEditField_5.Editable = 'off';
            app.PriceEditField_5.Value = 3;
            app.PriceEditField_6Label = uilabel(app.CellsPanel);
            app.PriceEditField_6Label.Text = 'Price';
            app.PriceEditField_6 = uieditfield(app.CellsPanel, 'numeric');
            app.PriceEditField_6.Editable = 'off';
            app.PriceEditField_6.Value = 5;
            app.PriceEditField_7Label = uilabel(app.CellsPanel);
            app.PriceEditField_7Label.Text = 'Price';
            app.PriceEditField_7 = uieditfield(app.CellsPanel, 'numeric');
            app.PriceEditField_7.Editable = 'off';
            app.PriceEditField_7.Value = 9;
            app.InventoryEditFieldLabel = uilabel(app.CellsPanel);
            app.InventoryEditFieldLabel.Text = 'Inventory';
            app.inventory1 = uieditfield(app.CellsPanel, 'numeric');
            app.inventory1.Editable = 'off';
            app.InventoryEditFieldLabel_2 = uilabel(app.CellsPanel);
            app.InventoryEditFieldLabel_2.Text = 'Inventory';
            app.inventory2 = uieditfield(app.CellsPanel, 'numeric');
            app.inventory2.Editable = 'off';
            app.InventoryEditFieldLabel_3 = uilabel(app.CellsPanel);
            app.InventoryEditFieldLabel_3.Text = 'Inventory';
            app.inventory3 = uieditfield(app.CellsPanel, 'numeric');
            app.inventory3.Editable = 'off';
            app.InventoryEditFieldLabel_4 = uilabel(app.CellsPanel);
            app.InventoryEditFieldLabel_4.Text = 'Inventory';
            app.inventory4 = uieditfield(app.CellsPanel, 'numeric');
            app.inventory4.Editable = 'off';
            app.ReplenishEditField_9Label = uilabel(app.CellsPanel);
            app.ReplenishEditField_9Label.Text = 'Replenish';
            app.Replenish1 = uieditfield(app.CellsPanel, 'numeric');
            app.Replenish1.Limits = [0 Inf];
            app.ReplenishEditField_10Label = uilabel(app.CellsPanel);
            app.ReplenishEditField_10Label.Text = 'Replenish';
            app.Replenish2 = uieditfield(app.CellsPanel, 'numeric');
            app.Replenish2.Limits = [0 Inf];
            app.ReplenishEditField_10Label_2 = uilabel(app.CellsPanel);
            app.ReplenishEditField_10Label_2.Text = 'Replenish';
            app.Replenish3 = uieditfield(app.CellsPanel, 'numeric');
            app.Replenish3.Limits = [0 Inf];
            app.ReplenishEditField_10Label_3 = uilabel(app.CellsPanel);
            app.ReplenishEditField_10Label_3.Text = 'Replenish';
            app.Replenish4 = uieditfield(app.CellsPanel, 'numeric');
            app.Replenish4.Limits = [0 Inf];
            app.WithdrawLabel = uilabel(app.CellsPanel);
            app.WithdrawLabel.Text = 'Withdraw';
            app.Withdraw1 = uieditfield(app.CellsPanel, 'numeric');
            app.Withdraw1.Limits = [0 Inf];
            app.WithdrawLabel_2 = uilabel(app.CellsPanel);
            app.WithdrawLabel_2.Text = 'Withdraw';
            app.Withdraw2 = uieditfield(app.CellsPanel, 'numeric');
            app.Withdraw2.Limits = [0 Inf];
            app.WithdrawLabel_3 = uilabel(app.CellsPanel);
            app.WithdrawLabel_3.Text = 'Withdraw';
            app.Withdraw3 = uieditfield(app.CellsPanel, 'numeric');
            app.Withdraw3.Limits = [0 Inf];
            app.WithdrawLabel_4 = uilabel(app.CellsPanel);
            app.WithdrawLabel_4.Text = 'Withdraw';
            app.Withdraw4 = uieditfield(app.CellsPanel, 'numeric');
            app.Withdraw4.Limits = [0 Inf];
            app.MoneyContainerPanel = uipanel(app.panel);
            app.MoneyContainerPanel.Title = 'Money Container';
            app.Cash1rLabel = uilabel(app.MoneyContainerPanel);
            app.Cash1rLabel.Text = 'Cash 1r';
            app.Cash5rLabel = uilabel(app.MoneyContainerPanel);
            app.Cash5rLabel.Text = 'Cash 5r';
            app.CoinHalfrLabel = uilabel(app.MoneyContainerPanel);
            app.CoinHalfrLabel.Text = 'Coin 0.5r';
            app.Coin1rLabel = uilabel(app.MoneyContainerPanel);
            app.Coin1rLabel.Text = 'Coin 1r';
            app.AmountEditFieldLabel = uilabel(app.MoneyContainerPanel);
            app.AmountEditFieldLabel.Text = 'Amount';
            app.AmountEditField = uieditfield(app.MoneyContainerPanel, 'numeric');
            app.AmountEditField.Editable = 'off';
            app.FillButton_5 = uibutton(app.MoneyContainerPanel, 'push');
            app.FillButton_5.ButtonPushedFcn = createCallbackFcn(app, @FillButton_5Pushed, true);
            app.FillButton_5.Text = 'Fill';
            app.FetchButton_5 = uibutton(app.MoneyContainerPanel, 'push');
            app.FetchButton_5.ButtonPushedFcn = createCallbackFcn(app, @FetchButton_5Pushed, true);
            app.FetchButton_5.Text = 'Fetch';
            app.FillButton_6 = uibutton(app.MoneyContainerPanel, 'push');
            app.FillButton_6.ButtonPushedFcn = createCallbackFcn(app, @FillButton_6Pushed, true);
            app.FillButton_6.Text = 'Fill';
            app.FetchButton_6 = uibutton(app.MoneyContainerPanel, 'push');
            app.FetchButton_6.ButtonPushedFcn = createCallbackFcn(app, @FetchButton_6Pushed, true);
            app.FetchButton_6.Text = 'Fetch';
            app.AmountEditField_2Label = uilabel(app.MoneyContainerPanel);
            app.AmountEditField_2Label.Text = 'Amount';
            app.AmountEditField_2 = uieditfield(app.MoneyContainerPanel, 'numeric');
            app.AmountEditField_2.Editable = 'off';
            app.FillButton_7 = uibutton(app.MoneyContainerPanel, 'push');
            app.FillButton_7.ButtonPushedFcn = createCallbackFcn(app, @FillButton_7Pushed, true);
            app.FillButton_7.Text = 'Fill';
            app.FetchButton_7 = uibutton(app.MoneyContainerPanel, 'push');
            app.FetchButton_7.ButtonPushedFcn = createCallbackFcn(app, @FetchButton_7Pushed, true);
            app.FetchButton_7.Text = 'Fetch';
            app.AmountEditField_3Label = uilabel(app.MoneyContainerPanel);
            app.AmountEditField_3Label.Text = 'Amount';
            app.AmountEditField_3 = uieditfield(app.MoneyContainerPanel, 'numeric');
            app.AmountEditField_3.Editable = 'off';
            app.FillButton_8 = uibutton(app.MoneyContainerPanel, 'push');
            app.FillButton_8.ButtonPushedFcn = createCallbackFcn(app, @FillButton_8Pushed, true);
            app.FillButton_8.Text = 'Fill';
            app.FetchButton_8 = uibutton(app.MoneyContainerPanel, 'push');
            app.FetchButton_8.ButtonPushedFcn = createCallbackFcn(app, @FetchButton_8Pushed, true);
            app.FetchButton_8.Text = 'Fetch';
            app.AmountEditField_4Label = uilabel(app.MoneyContainerPanel);
            app.AmountEditField_4Label.Text = 'Amount';
            app.AmountEditField_4 = uieditfield(app.MoneyContainerPanel, 'numeric');
            app.AmountEditField_4.Editable = 'off';
            app.MoneyLamp3 = uilamp(app.MoneyContainerPanel);
            app.MoneyLamp4 = uilamp(app.MoneyContainerPanel);
            app.MoneyLamp2 = uilamp(app.MoneyContainerPanel);
            app.MoneyLamp1 = uilamp(app.MoneyContainerPanel);
            app.Replenish1_2 = uieditfield(app.MoneyContainerPanel, 'numeric');
            app.Replenish1_2.Limits = [0 Inf];
            app.Replenish2_2 = uieditfield(app.MoneyContainerPanel, 'numeric');
            app.Replenish2_2.Limits = [0 Inf];
            app.Replenish3_2 = uieditfield(app.MoneyContainerPanel, 'numeric');
            app.Replenish3_2.Limits = [0 Inf];
            app.Replenish4_2 = uieditfield(app.MoneyContainerPanel, 'numeric');
            app.Replenish4_2.Limits = [0 Inf];
            app.Withdraw1_2 = uieditfield(app.MoneyContainerPanel, 'numeric');
            app.Withdraw1_2.Limits = [0 Inf];
            app.Withdraw2_2 = uieditfield(app.MoneyContainerPanel, 'numeric');
            app.Withdraw2_2.Limits = [0 Inf];
            app.Withdraw3_2 = uieditfield(app.MoneyContainerPanel, 'numeric');
            app.Withdraw3_2.Limits = [0 Inf];
            app.Withdraw4_2 = uieditfield(app.MoneyContainerPanel, 'numeric');
            app.Withdraw4_2.Limits = [0 Inf];
            app.ReplenishEditField_9Label_2 = uilabel(app.MoneyContainerPanel);
            app.ReplenishEditField_9Label_2.Text = 'Replenish';
            app.ReplenishEditField_10Label_4 = uilabel(app.MoneyContainerPanel);
            app.ReplenishEditField_10Label_4.Text = 'Replenish';
            app.ReplenishEditField_10Label_5 = uilabel(app.MoneyContainerPanel);
            app.ReplenishEditField_10Label_5.Text = 'Replenish';
            app.ReplenishEditField_10Label_6 = uilabel(app.MoneyContainerPanel);
            app.ReplenishEditField_10Label_6.Text = 'Replenish';
            app.WithdrawLabel_5 = uilabel(app.MoneyContainerPanel);
            app.WithdrawLabel_5.Text = 'Withdraw';
            app.WithdrawLabel_6 = uilabel(app.MoneyContainerPanel);
            app.WithdrawLabel_6.Text = 'Withdraw';
            app.WithdrawLabel_7 = uilabel(app.MoneyContainerPanel);
            app.WithdrawLabel_7.Text = 'Withdraw';
            app.WithdrawLabel_8 = uilabel(app.MoneyContainerPanel);
            app.WithdrawLabel_8.Text = 'Withdraw';
            app.LampColorIndicationButton = uibutton(app.panel, 'push');
            app.LampColorIndicationButton.ButtonPushedFcn = createCallbackFcn(app, @LampColorIndicationButtonPushed, true);
            app.LampColorIndicationButton.Text = 'Lamp Color Indication';
            app.FixButton = uibutton(app.panel, 'push');
            app.FixButton.ButtonPushedFcn = createCallbackFcn(app, @FixButtonPushed, true);
            app.FixButton.Text = 'Fix';
            app.UserproblemLampLabel = uilabel(app.panel);
            app.UserproblemLampLabel.Text = 'User problem';
            app.UserproblemLamp = uilamp(app.panel);
            app.OfflineMaintainPanelLabel = uilabel(app.panel);
            app.OfflineMaintainPanelLabel.Text = 'Offline Maintain Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OfflineMaintainPanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end