classdef OnlinePanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OnlineMaintainPanelLabel        matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        ClearAllandExporttoExcelButton  matlab.ui.control.Button
        StuationCheckPanel              matlab.ui.container.Panel
        Cash1rLabel                     matlab.ui.control.Label
        Cash5rLabel                     matlab.ui.control.Label
        CoinHalfrLabel                  matlab.ui.control.Label
        Coin1rLabel                     matlab.ui.control.Label
        Lamp1                           matlab.ui.control.Lamp
        Lamp2                           matlab.ui.control.Lamp
        Lamp3                           matlab.ui.control.Lamp
        Lamp4                           matlab.ui.control.Lamp
        AmountEditFieldLabel_2          matlab.ui.control.Label
        cash1Amount                     matlab.ui.control.NumericEditField
        AmountEditFieldLabel_3          matlab.ui.control.Label
        cash5Amount                     matlab.ui.control.NumericEditField
        AmountEditFieldLabel_4          matlab.ui.control.Label
        coinHalfAmount                  matlab.ui.control.NumericEditField
        AmountEditFieldLabel_5          matlab.ui.control.Label
        coin1Amount                     matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_7                          matlab.ui.control.Lamp
        Lamp_8                          matlab.ui.control.Lamp
        InventoryEditField_2Label       matlab.ui.control.Label
        inventory2                      matlab.ui.control.NumericEditField
        InventoryEditField_2Label_2     matlab.ui.control.Label
        inventory1                      matlab.ui.control.NumericEditField
        InventoryEditField_2Label_3     matlab.ui.control.Label
        inventory3                      matlab.ui.control.NumericEditField
        InventoryEditField_2Label_4     matlab.ui.control.Label
        inventory4                      matlab.ui.control.NumericEditField
        GiveanintegerfornamingEditFieldLabel  matlab.ui.control.Label
        GiveanintegerfornamingEditField  matlab.ui.control.NumericEditField
        GetCalledbyUserLampLabel        matlab.ui.control.Label
        GetCalledbyUserLamp             matlab.ui.control.Lamp
    end

    
    properties (Access = public)
       vending
    end
    
    methods (Access = public)
        function color = inventoryLightIndicator(app, inventory)
           if inventory ==  0
                   color = [1 0 0];
           elseif inventory < 5 && inventory > 0
               color = [1 1 0.07];
           elseif inventory >= 5 && inventory <= 95
               color = [0 1 0];
           elseif inventory > 95 && inventory < 100
               color = [0 0 1];
           elseif inventory == 100
               color = [0 0 0];
           else
               % should not happen
               color = [1 1 1];
           end
        end
        
        function color = moneyLightIndicator(app, amount)
           if amount ==  0
                   color = [1 0 0];
           elseif amount < 50 && amount > 0
               color = [1 1 0.07];
           elseif amount >= 50 && amount <= 950
               color = [0 1 0];
           elseif amount > 950 && amount < 1000
               color = [0 0 1];
           elseif amount == 1000
               color = [0 0 0];
           else
               % should not happen
               color = [1 1 1];
           end
        end
        
        function updateCellLamp(app, inventory1, inventory2, inventory3, inventory4)
            % i = [inventory1, inventory2, inventory3, inventory4]
            app.inventory1.Value = inventory1;
            app.inventory2.Value = inventory2;
            app.inventory3.Value = inventory3;
            app.inventory4.Value = inventory4;

            app.Lamp_5.Color = app.inventoryLightIndicator(inventory1);
            app.Lamp_6.Color = app.inventoryLightIndicator(inventory2);
            app.Lamp_7.Color = app.inventoryLightIndicator(inventory3);
            app.Lamp_8.Color = app.inventoryLightIndicator(inventory4);
        end
   
        function updateMoneyLamp(app, cash1r, cash5r, coinHalfr, coin1r)
            % c = [cash1r, cash5r, coinHalfr, coin1r];
            app.cash1Amount.Value = cash1r;
            app.cash5Amount.Value = cash5r;
            app.coinHalfAmount.Value = coinHalfr;
            app.coin1Amount.Value = coin1r;
            
            app.Lamp1.Color = app.moneyLightIndicator(cash1r);
            app.Lamp2.Color = app.moneyLightIndicator(cash5r);
            app.Lamp3.Color = app.moneyLightIndicator(coinHalfr);
            app.Lamp4.Color = app.moneyLightIndicator(coin1r);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClearAllandExporttoExcelButton
        function ClearAllandExporttoExcelButtonPushed(app, event)
            if app.GiveanintegerfornamingEditField.Value ~= 0
                fileName = string(int32(app.GiveanintegerfornamingEditField.Value))+".xlsx";
            else
                % if user does not input anything, use current time to name.
                a = clock;
                timeStamp = a(2)*100000000 + a(3)*1000000 + a(4)*10000 +a(5)*100 + int64(a(6));
                fileName =  string(timeStamp) + ".xlsx";
            end
            app.vending.logSys.saveCurrentLog2Excel(fileName);   
            app.UITable.Data = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OnlineMaintainPanelLabel = uilabel(app.UIFigure);
            app.OnlineMaintainPanelLabel.Text = 'Online Maintain Panel';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Time Stamp'; 'ID'; 'Price'; 'Input Money'; 'Remain Change'; 'Get Product'};
            app.UITable.RowName = {};
            app.ClearAllandExporttoExcelButton = uibutton(app.UIFigure, 'push');
            app.ClearAllandExporttoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllandExporttoExcelButtonPushed, true);
            app.ClearAllandExporttoExcelButton.Text = 'Clear All and Export to Excel';
            app.StuationCheckPanel = uipanel(app.UIFigure);
            app.StuationCheckPanel.Title = 'Stuation Check';
            app.Cash1rLabel = uilabel(app.StuationCheckPanel);
            app.Cash1rLabel.Text = 'Cash 1r';
            app.Cash5rLabel = uilabel(app.StuationCheckPanel);
            app.Cash5rLabel.Text = 'Cash 5r';
            app.CoinHalfrLabel = uilabel(app.StuationCheckPanel);
            app.CoinHalfrLabel.Text = 'Coin 0.5r';
            app.Coin1rLabel = uilabel(app.StuationCheckPanel);
            app.Coin1rLabel.Text = 'Coin 1r';
            app.Lamp1 = uilamp(app.StuationCheckPanel);
            app.Lamp2 = uilamp(app.StuationCheckPanel);
            app.Lamp3 = uilamp(app.StuationCheckPanel);
            app.Lamp4 = uilamp(app.StuationCheckPanel);
            app.AmountEditFieldLabel_2 = uilabel(app.StuationCheckPanel);
            app.AmountEditFieldLabel_2.Text = 'Amount';
            app.cash1Amount = uieditfield(app.StuationCheckPanel, 'numeric');
            app.cash1Amount.Editable = 'off';
            app.AmountEditFieldLabel_3 = uilabel(app.StuationCheckPanel);
            app.AmountEditFieldLabel_3.Text = 'Amount';
            app.cash5Amount = uieditfield(app.StuationCheckPanel, 'numeric');
            app.cash5Amount.Editable = 'off';
            app.AmountEditFieldLabel_4 = uilabel(app.StuationCheckPanel);
            app.AmountEditFieldLabel_4.Text = 'Amount';
            app.coinHalfAmount = uieditfield(app.StuationCheckPanel, 'numeric');
            app.coinHalfAmount.Editable = 'off';
            app.AmountEditFieldLabel_5 = uilabel(app.StuationCheckPanel);
            app.AmountEditFieldLabel_5.Text = 'Amount';
            app.coin1Amount = uieditfield(app.StuationCheckPanel, 'numeric');
            app.coin1Amount.Editable = 'off';
            app.Label = uilabel(app.StuationCheckPanel);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.StuationCheckPanel);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.StuationCheckPanel);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.StuationCheckPanel);
            app.Label_4.Text = '4';
            app.Image = uiimage(app.StuationCheckPanel);
            app.Image.ImageSource = 'cola.png';
            app.Image2 = uiimage(app.StuationCheckPanel);
            app.Image2.ImageSource = 'OIP.jpg';
            app.Image3 = uiimage(app.StuationCheckPanel);
            app.Image3.ImageSource = '506975_1_pic500_3755.jpg';
            app.Image4 = uiimage(app.StuationCheckPanel);
            app.Image4.ImageSource = '巧克力.jpg';
            app.Lamp_5 = uilamp(app.StuationCheckPanel);
            app.Lamp_6 = uilamp(app.StuationCheckPanel);
            app.Lamp_7 = uilamp(app.StuationCheckPanel);
            app.Lamp_8 = uilamp(app.StuationCheckPanel);
            app.InventoryEditField_2Label = uilabel(app.StuationCheckPanel);
            app.InventoryEditField_2Label.Text = 'Inventory';
            app.inventory2 = uieditfield(app.StuationCheckPanel, 'numeric');
            app.inventory2.Editable = 'off';
            app.InventoryEditField_2Label_2 = uilabel(app.StuationCheckPanel);
            app.InventoryEditField_2Label_2.Text = 'Inventory';
            app.inventory1 = uieditfield(app.StuationCheckPanel, 'numeric');
            app.inventory1.Editable = 'off';
            app.InventoryEditField_2Label_3 = uilabel(app.StuationCheckPanel);
            app.InventoryEditField_2Label_3.Text = 'Inventory';
            app.inventory3 = uieditfield(app.StuationCheckPanel, 'numeric');
            app.inventory3.Editable = 'off';
            app.InventoryEditField_2Label_4 = uilabel(app.StuationCheckPanel);
            app.InventoryEditField_2Label_4.Text = 'Inventory';
            app.inventory4 = uieditfield(app.StuationCheckPanel, 'numeric');
            app.inventory4.Editable = 'off';
            app.GiveanintegerfornamingEditFieldLabel = uilabel(app.UIFigure);
            app.GiveanintegerfornamingEditFieldLabel.Text = 'Give an integer for naming';
            app.GiveanintegerfornamingEditField = uieditfield(app.UIFigure, 'numeric');
            app.GiveanintegerfornamingEditField.Value = 2333;
            app.GetCalledbyUserLampLabel = uilabel(app.UIFigure);
            app.GetCalledbyUserLampLabel.Text = 'Get Called by User';
            app.GetCalledbyUserLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OnlinePanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end