classdef Panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CellsPanel                   matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        IwantthisButton              matlab.ui.control.Button
        Label_2                      matlab.ui.control.Label
        IwantthisButton_2            matlab.ui.control.Button
        Label_3                      matlab.ui.control.Label
        IwantthisButton_3            matlab.ui.control.Button
        Label_4                      matlab.ui.control.Label
        IwantthisButton_4            matlab.ui.control.Button
        Lamp1                        matlab.ui.control.Lamp
        Lamp2                        matlab.ui.control.Lamp
        Lamp3                        matlab.ui.control.Lamp
        Lamp4                        matlab.ui.control.Lamp
        Image                        matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        Image3                       matlab.ui.control.Image
        Image4                       matlab.ui.control.Image
        DisplayRegionPanel           matlab.ui.container.Panel
        ConfirmButton                matlab.ui.control.Button
        productEditFieldLabel        matlab.ui.control.Label
        productEditField             matlab.ui.control.NumericEditField
        ItcostsEditFieldLabel        matlab.ui.control.Label
        ItcostsEditField             matlab.ui.control.NumericEditField
        FinishInsertingButton        matlab.ui.control.Button
        YouhaveInsertLabel           matlab.ui.control.Label
        cash1rLabel_3                matlab.ui.control.Label
        cash1display                 matlab.ui.control.NumericEditField
        cash5rLabel_3                matlab.ui.control.Label
        cash5display                 matlab.ui.control.NumericEditField
        coin1rLabel_3                matlab.ui.control.Label
        coin1display                 matlab.ui.control.NumericEditField
        TotalmoneyinsertedEditFieldLabel  matlab.ui.control.Label
        TotalmoneyinsertedEditField  matlab.ui.control.NumericEditField
        YouchoseLabel                matlab.ui.control.Label
        coin05rLabel                 matlab.ui.control.Label
        coinHalfDisplay              matlab.ui.control.NumericEditField
        finishInsertLamp             matlab.ui.control.Lamp
        MoneyInputPanel              matlab.ui.container.Panel
        CashButtonGroup              matlab.ui.container.ButtonGroup
        cash1                        matlab.ui.control.RadioButton
        cash5                        matlab.ui.control.RadioButton
        cashFakeDisplay              matlab.ui.control.RadioButton
        cashFake                     matlab.ui.control.NumericEditField
        cashFakeLamp                 matlab.ui.control.Lamp
        InsertCashButton             matlab.ui.control.Button
        InsertCoinButton             matlab.ui.control.Button
        CoinButtonGroup              matlab.ui.container.ButtonGroup
        coin1                        matlab.ui.control.RadioButton
        coinFakeDisplay              matlab.ui.control.RadioButton
        coinFake                     matlab.ui.control.NumericEditField
        coinHalf                     matlab.ui.control.RadioButton
        coinFakeLamp                 matlab.ui.control.Lamp
        Onlytakecash1r5randcoin05r1rLabel  matlab.ui.control.Label
        FetchCash                    matlab.ui.control.Button
        FetchCoin                    matlab.ui.control.Button
        OfflineUserPanelLabel        matlab.ui.control.Label
        CallMaintainerButton         matlab.ui.control.Button
        ChangeProductOutputPanel     matlab.ui.container.Panel
        coin05rLabel_2               matlab.ui.control.Label
        changeCoinHalfDisplay        matlab.ui.control.NumericEditField
        coin1rLabel_2                matlab.ui.control.Label
        changeCoin1display           matlab.ui.control.NumericEditField
        ProductOutLampLabel          matlab.ui.control.Label
        ProductOutLamp               matlab.ui.control.Lamp
        fetchallButton               matlab.ui.control.Button
        ChangeoutLampLabel           matlab.ui.control.Label
        ChangeoutLamp                matlab.ui.control.Lamp
        cash1rLabel_4                matlab.ui.control.Label
        changeCash1rDisplay          matlab.ui.control.NumericEditField
        cash5rLabel_4                matlab.ui.control.Label
        changeCash5rDisplay          matlab.ui.control.NumericEditField
        GoMaintainingPanel           matlab.ui.container.Panel
        GoButton                     matlab.ui.control.Button
        MaintainCodeEditFieldLabel   matlab.ui.control.Label
        MaintainCodeEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        purchase
        vending        
    end
    
    methods (Access = private)
        
        function chooseAProduct(app, cid)
            if app.purchase.finishChoosingProduct ~= 1
                app.purchase.productID = cid;
                app.purchase.productPrice = app.vending.cellsSys.getPrice(cid);
                app.productEditField.Value = app.purchase.productID;
                app.ItcostsEditField.Value = app.purchase.productPrice;   
                app.purchase.startChoosing = 1;         
            end
        end
    end
    
    methods (Access = public)
        
        function setYouChosePartEditability(app, flag)
            % make the 'You chose' part look editable(1) or uneditable(0).
            if flag == 1
                % brach T cover 1.6.1.1
                app.productEditField.BackgroundColor = [1.0 1.0 1.0];
                app.ItcostsEditField.BackgroundColor = [1.0 1.0 1.0];
            elseif flag == 0
                % brach T cover 1.6.1.2
                app.productEditField.BackgroundColor = [0.9 0.9 0.9];
                app.ItcostsEditField.BackgroundColor = [0.9 0.9 0.9];
            end  
        end

        function setYouHaveInsertedPartEditability(app, flag)
            % make the 'You have inserted' part look editable(1) or uneditable(0).
            if flag == 1
                % brach T cover 1.6.2.1
                app.coin1display.BackgroundColor = [1.0 1.0 1.0];
                app.cash1display.BackgroundColor = [1.0 1.0 1.0];
                app.cash5display.BackgroundColor = [1.0 1.0 1.0];
                app.coinHalfDisplay.BackgroundColor = [1.0 1.0 1.0];
                app.TotalmoneyinsertedEditField.BackgroundColor = [1.0 1.0 1.0];
            elseif flag == 0
                % brach T cover 1.6.2.2
                 app.coin1display.BackgroundColor = [0.9 0.9 0.9];
                 app.cash1display.BackgroundColor = [0.9 0.9 0.9];
                 app.cash5display.BackgroundColor = [0.9 0.9 0.9];
                 app.coinHalfDisplay.BackgroundColor = [0.9 0.9 0.9];
                 app.TotalmoneyinsertedEditField.BackgroundColor = [0.9 0.9 0.9];
            end
        end

        
        function resetDisplayRegion(app)
            % statement T cover 1.6.3.1
            app.TotalmoneyinsertedEditField.Value = 0;
            app.cash1display.Value = 0;
            app.cash5display.Value = 0;
            app.coinHalfDisplay.Value = 0;
            app.coin1display.Value = 0;
            app.ItcostsEditField.Value = 0;
            app.productEditField.Value = 0;
        end
       
        function resetMoneyRegion(app)
            % statement T cover 1.6.4.1
           app.InsertCoinButton.Enable = "off";
           app.InsertCashButton.Enable = "off";
        end
        
        function resetOutputRegion(app)
            % statement T cover 1.6.5.1
            app.ChangeoutLamp.Color = [0.8 0.8 0.8];
            app.ProductOutLamp.Color = [0.8 0.8 0.8];
            app.changeCoin1display.Value = 0;
            app.changeCoinHalfDisplay.Value = 0;
            app.changeCash1rDisplay.Value = 0;
            app.changeCash5rDisplay.Value = 0;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IwantthisButton
        function IwantthisButtonPushed(app, event)
            app.chooseAProduct(1);
        end

        % Button pushed function: IwantthisButton_2
        function IwantthisButton_2Pushed(app, event)
            app.chooseAProduct(2);
        end

        % Button pushed function: IwantthisButton_3
        function IwantthisButton_3Pushed(app, event)
            app.chooseAProduct(3);
        end

        % Button pushed function: IwantthisButton_4
        function IwantthisButton_4Pushed(app, event)
            app.chooseAProduct(4);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % only when choosing some staff already, pushing confirm is usable.
            if app.purchase.startChoosing == 1 && app.purchase.finishChoosingProduct == 0 && app.purchase.finishInsertingMoney == 0
                % set money insertion part
                app.finishInsertLamp.Color = [1 0 0];
                app.purchase.finishChoosingProduct = 1;
                app.setYouChosePartEditability(0);
                app.InsertCoinButton.Enable = "on";
                app.InsertCashButton.Enable = "on";                
            end            
        end

        % Button pushed function: InsertCashButton
        function InsertCashButtonPushed(app, event)
            % should change temporary money container in theory! Do this by
            % now for convenience.
            if app.purchase.startChoosing == 1 && app.purchase.finishChoosingProduct == 1 && app.purchase.finishInsertingMoney == 0
                if app.cash1.Value == 1
                    app.cash1display.Value = app.cash1display.Value + 1;
                    app.TotalmoneyinsertedEditField.Value = app.TotalmoneyinsertedEditField.Value + 1;
                elseif app.cash5.Value == 1
                    app.cash5display.Value = app.cash5display.Value + 1;
                    app.TotalmoneyinsertedEditField.Value = app.TotalmoneyinsertedEditField.Value + 5;
                elseif app.cashFakeDisplay.Value == 1
                    FakePanel();
                    app.cashFake.Value = app.cashFake.Value + 1;
                    app.cashFakeLamp.Color = [0 1 0];
                    app.InsertCashButton.Enable = "off";
                end    
                
            if app.TotalmoneyinsertedEditField.Value >= app.purchase.productPrice
                % lamp to green and do not allow for any insertion afterwards
                app.finishInsertLamp.Color = [0 1 0];
                app.InsertCashButton.Enable = "off";
                app.InsertCoinButton.Enable = "off";
            end
            
            end
        end

        % Button pushed function: InsertCoinButton
        function InsertCoinButtonPushed(app, event)
            if app.purchase.startChoosing == 1 && app.purchase.finishChoosingProduct == 1 && app.purchase.finishInsertingMoney == 0
                if app.coin1.Value == 1
                    app.coin1display.Value = app.coin1display.Value + 1;
                    app.TotalmoneyinsertedEditField.Value = app.TotalmoneyinsertedEditField.Value + 1;
                elseif app.coinHalf.Value == 1
                    app.coinHalfDisplay.Value = app.coinHalfDisplay.Value + 1;
                    app.TotalmoneyinsertedEditField.Value = app.TotalmoneyinsertedEditField.Value + 0.5;
                elseif app.coinFakeDisplay.Value == 1
                    FakePanel();
                    app.coinFake.Value = app.coinFake.Value + 1;
                    app.coinFakeLamp.Color = [0 1 0];
                    app.InsertCoinButton.Enable = "off";
                end                
            end

            if app.TotalmoneyinsertedEditField.Value >= app.purchase.productPrice
                % lamp to green and do not allow for any insertion afterwards
                app.finishInsertLamp.Color = [0 1 0];
                app.InsertCashButton.Enable = "off";
                app.InsertCoinButton.Enable = "off";
            end

        end

        % Button pushed function: FinishInsertingButton
        function FinishInsertingButtonPushed(app, event)
            % TBA: if money is not enough, then make some sound.
            if app.TotalmoneyinsertedEditField.Value >= app.purchase.productPrice && app.purchase.startChoosing == 1 && app.purchase.finishChoosingProduct == 1 && app.purchase.finishInsertingMoney == 0
                 app.finishInsertLamp.Color = [0 1 0];
                 app.purchase.finishInsertingMoney = 1;

                 v = app.vending.moneyProcessor.refund(app.purchase.productPrice, app.TotalmoneyinsertedEditField.Value);
                 if v(1) ~= -1 % enough money
                     app.purchase.remainChange = 0;
                     app.changeCoinHalfDisplay.Value = v(1);
                     app.changeCoin1display.Value = v(2);   
                     app.changeCash1rDisplay.Value = 0;    
                     app.changeCash5rDisplay.Value = 0;
                 else % not enough money - get all input out.
                     app.purchase.remainChange = v(2);
                     app.changeCoinHalfDisplay.Value = app.coinHalfDisplay.Value;
                     app.changeCoin1display.Value = app.coin1display.Value;    
                     app.changeCash1rDisplay.Value = app.cash1display.Value;    
                     app.changeCash5rDisplay.Value = app.cash5display.Value; 
                 end
                 
                 if app.purchase.remainChange == 0
                     % refund normally
                     app.ChangeoutLamp.Color = [0 1 0];
                 else 
                     app.ChangeoutLamp.Color = [1 0 0];
                 end
                 
                 % product Mistake or not enough money for change
                 if rand(1) < app.vending.mistakeP || app.purchase.remainChange ~= 0
                     app.purchase.getProduct = 0;      
                     app.ProductOutLamp.Color = [1 0 0]; 
                 else
                    app.purchase.getProduct = 1;
                    app.ProductOutLamp.Color = [0 1 0]; 
                 end

                 app.setYouHaveInsertedPartEditability(0);
                 app.finishInsertLamp.Color = [0.8 0.8 0.8];
                 
            end
        end

        % Button pushed function: fetchallButton
        function fetchallButtonPushed(app, event)
            if app.purchase.startChoosing == 1 && app.purchase.finishChoosingProduct == 1 && app.purchase.finishInsertingMoney == 1
                if app.purchase.remainChange == 0
                    app.vending.finishOnePurchase(app.purchase.productID, app.cash1display.Value, app.cash5display.Value, app.coinHalfDisplay.Value-app.changeCoinHalfDisplay.Value,app.coin1display.Value-app.changeCoin1display.Value);
                else % no change should be done in money maintainer or cell 
                    app.vending.finishOnePurchase(-1,0,0,0,0);
                end      
            end
        end

        % Button pushed function: FetchCash
        function FetchCashButtonPushed(app, event)
            app.cashFakeLamp.Color = [0.8 0.8 0.8];
            app.cashFake.Value = 0;
            if app.purchase.startChoosing == 1 && app.purchase.finishChoosingProduct == 1 && app.purchase.productPrice > app.TotalmoneyinsertedEditField.Value
                % need to fetch fake money before inserting any money -
                % this if is only executed when money is still needed to be
                % inserted (fake money can be remained on the part)
                app.InsertCashButton.Enable = "on";                
            end
        end

        % Button pushed function: FetchCoin
        function FetchCoinButtonPushed(app, event)
            app.coinFakeLamp.Color = [0.8 0.8 0.8];
            app.coinFake.Value = 0;  
            if app.purchase.startChoosing == 1 && app.purchase.finishChoosingProduct == 1 && app.purchase.productPrice > app.TotalmoneyinsertedEditField.Value
                % need to fetch fake money before inserting any money -
                % this if is only executed when money is still needed to be
                % inserted 
                app.InsertCoinButton.Enable = "on";                
            end

        end

        % Button pushed function: CallMaintainerButton
        function CallMaintainerButtonPushed(app, event)
            app.vending.getUserProblem();
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            if app.MaintainCodeEditField.Value == 1
                app.vending.maintainSys.offlinePanel.panel.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CellsPanel = uipanel(app.UIFigure);
            app.CellsPanel.Title = 'Cells';
            app.Label = uilabel(app.CellsPanel);
            app.Label.Text = '1';
            app.IwantthisButton = uibutton(app.CellsPanel, 'push');
            app.IwantthisButton.ButtonPushedFcn = createCallbackFcn(app, @IwantthisButtonPushed, true);
            app.IwantthisButton.Text = 'I want this';
            app.Label_2 = uilabel(app.CellsPanel);
            app.Label_2.Text = '2';
            app.IwantthisButton_2 = uibutton(app.CellsPanel, 'push');
            app.IwantthisButton_2.ButtonPushedFcn = createCallbackFcn(app, @IwantthisButton_2Pushed, true);
            app.IwantthisButton_2.Text = 'I want this';
            app.Label_3 = uilabel(app.CellsPanel);
            app.Label_3.Text = '3';
            app.IwantthisButton_3 = uibutton(app.CellsPanel, 'push');
            app.IwantthisButton_3.ButtonPushedFcn = createCallbackFcn(app, @IwantthisButton_3Pushed, true);
            app.IwantthisButton_3.Text = 'I want this';
            app.Label_4 = uilabel(app.CellsPanel);
            app.Label_4.Text = '4';
            app.IwantthisButton_4 = uibutton(app.CellsPanel, 'push');
            app.IwantthisButton_4.ButtonPushedFcn = createCallbackFcn(app, @IwantthisButton_4Pushed, true);
            app.IwantthisButton_4.Text = 'I want this';
            app.Lamp1 = uilamp(app.CellsPanel);
            app.Lamp2 = uilamp(app.CellsPanel);
            app.Lamp3 = uilamp(app.CellsPanel);
            app.Lamp4 = uilamp(app.CellsPanel);
            app.Image = uiimage(app.CellsPanel);
            app.Image.ImageSource = 'cola.png';
            app.Image2 = uiimage(app.CellsPanel);
            app.Image2.ImageSource = 'OIP.jpg';
            app.Image3 = uiimage(app.CellsPanel);
            app.Image3.ImageSource = '506975_1_pic500_3755.jpg';
            app.Image4 = uiimage(app.CellsPanel);
            app.Image4.ImageSource = '巧克力.jpg';
            app.DisplayRegionPanel = uipanel(app.UIFigure);
            app.DisplayRegionPanel.Title = 'Display Region';
            app.ConfirmButton = uibutton(app.DisplayRegionPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.productEditFieldLabel = uilabel(app.DisplayRegionPanel);
            app.productEditFieldLabel.Text = 'product';
            app.productEditField = uieditfield(app.DisplayRegionPanel, 'numeric');
            app.productEditField.Editable = 'off';
            app.ItcostsEditFieldLabel = uilabel(app.DisplayRegionPanel);
            app.ItcostsEditFieldLabel.Text = 'It costs';
            app.ItcostsEditField = uieditfield(app.DisplayRegionPanel, 'numeric');
            app.ItcostsEditField.Editable = 'off';
            app.FinishInsertingButton = uibutton(app.DisplayRegionPanel, 'push');
            app.FinishInsertingButton.ButtonPushedFcn = createCallbackFcn(app, @FinishInsertingButtonPushed, true);
            app.FinishInsertingButton.Text = 'Finish Inserting';
            app.YouhaveInsertLabel = uilabel(app.DisplayRegionPanel);
            app.YouhaveInsertLabel.Text = 'You have Insert';
            app.cash1rLabel_3 = uilabel(app.DisplayRegionPanel);
            app.cash1rLabel_3.Text = 'cash:1r';
            app.cash1display = uieditfield(app.DisplayRegionPanel, 'numeric');
            app.cash1display.Editable = 'off';
            app.cash5rLabel_3 = uilabel(app.DisplayRegionPanel);
            app.cash5rLabel_3.Text = 'cash:5r';
            app.cash5display = uieditfield(app.DisplayRegionPanel, 'numeric');
            app.cash5display.Editable = 'off';
            app.coin1rLabel_3 = uilabel(app.DisplayRegionPanel);
            app.coin1rLabel_3.Text = 'coin:1r';
            app.coin1display = uieditfield(app.DisplayRegionPanel, 'numeric');
            app.coin1display.Editable = 'off';
            app.TotalmoneyinsertedEditFieldLabel = uilabel(app.DisplayRegionPanel);
            app.TotalmoneyinsertedEditFieldLabel.Text = 'Total money inserted';
            app.TotalmoneyinsertedEditField = uieditfield(app.DisplayRegionPanel, 'numeric');
            app.TotalmoneyinsertedEditField.Editable = 'off';
            app.YouchoseLabel = uilabel(app.DisplayRegionPanel);
            app.YouchoseLabel.Text = 'You chose';
            app.coin05rLabel = uilabel(app.DisplayRegionPanel);
            app.coin05rLabel.Text = 'coin:0.5r';
            app.coinHalfDisplay = uieditfield(app.DisplayRegionPanel, 'numeric');
            app.coinHalfDisplay.Editable = 'off';
            app.finishInsertLamp = uilamp(app.DisplayRegionPanel);
            app.MoneyInputPanel = uipanel(app.UIFigure);
            app.MoneyInputPanel.Title = 'Money Input ';
            app.CashButtonGroup = uibuttongroup(app.MoneyInputPanel);
            app.CashButtonGroup.Title = 'Cash';
            app.cash1 = uiradiobutton(app.CashButtonGroup);
            app.cash1.Text = '1r';
            app.cash1.Value = true;
            app.cash5 = uiradiobutton(app.CashButtonGroup);
            app.cash5.Text = '5r';
            app.cashFakeDisplay = uiradiobutton(app.CashButtonGroup);
            app.cashFakeDisplay.Text = 'fake & illegal';
            app.cashFake = uieditfield(app.CashButtonGroup, 'numeric');
            app.cashFakeLamp = uilamp(app.CashButtonGroup);
            app.InsertCashButton = uibutton(app.MoneyInputPanel, 'push');
            app.InsertCashButton.ButtonPushedFcn = createCallbackFcn(app, @InsertCashButtonPushed, true);
            app.InsertCashButton.Text = {'Insert'; ''};
            app.InsertCoinButton = uibutton(app.MoneyInputPanel, 'push');
            app.InsertCoinButton.ButtonPushedFcn = createCallbackFcn(app, @InsertCoinButtonPushed, true);
            app.InsertCoinButton.Text = 'Insert';
            app.CoinButtonGroup = uibuttongroup(app.MoneyInputPanel);
            app.CoinButtonGroup.Title = 'Coin';
            app.coin1 = uiradiobutton(app.CoinButtonGroup);
            app.coin1.Text = '1r';
            app.coin1.Value = true;
            app.coinFakeDisplay = uiradiobutton(app.CoinButtonGroup);
            app.coinFakeDisplay.Text = 'fake & illegal';
            app.coinFake = uieditfield(app.CoinButtonGroup, 'numeric');
            app.coinHalf = uiradiobutton(app.CoinButtonGroup);
            app.coinHalf.Text = '0.5r';
            app.coinFakeLamp = uilamp(app.CoinButtonGroup);
            app.Onlytakecash1r5randcoin05r1rLabel = uilabel(app.MoneyInputPanel);
            app.Onlytakecash1r5randcoin05r1rLabel.Text = 'Only take cash (1r, 5r) and coin (0.5r,1r).';
            app.FetchCash = uibutton(app.MoneyInputPanel, 'push');
            app.FetchCash.ButtonPushedFcn = createCallbackFcn(app, @FetchCashButtonPushed, true);
            app.FetchCash.Text = 'Fetch';
            app.FetchCoin = uibutton(app.MoneyInputPanel, 'push');
            app.FetchCoin.ButtonPushedFcn = createCallbackFcn(app, @FetchCoinButtonPushed, true);
            app.FetchCoin.Text = 'Fetch';
            app.OfflineUserPanelLabel = uilabel(app.UIFigure);
            app.OfflineUserPanelLabel.Text = 'Offline User Panel';
            app.CallMaintainerButton = uibutton(app.UIFigure, 'push');
            app.CallMaintainerButton.ButtonPushedFcn = createCallbackFcn(app, @CallMaintainerButtonPushed, true);
            app.CallMaintainerButton.Text = 'Call Maintainer';
            app.ChangeProductOutputPanel = uipanel(app.UIFigure);
            app.ChangeProductOutputPanel.Title = 'Change & Product Output';
            app.coin05rLabel_2 = uilabel(app.ChangeProductOutputPanel);
            app.coin05rLabel_2.Text = 'coin:0.5r';
            app.changeCoinHalfDisplay = uieditfield(app.ChangeProductOutputPanel, 'numeric');
            app.changeCoinHalfDisplay.Editable = 'off';
            app.coin1rLabel_2 = uilabel(app.ChangeProductOutputPanel);
            app.coin1rLabel_2.Text = 'coin:1r';
            app.changeCoin1display = uieditfield(app.ChangeProductOutputPanel, 'numeric');
            app.changeCoin1display.Editable = 'off';
            app.ProductOutLampLabel = uilabel(app.ChangeProductOutputPanel);
            app.ProductOutLampLabel.Text = 'Product Out';
            app.ProductOutLamp = uilamp(app.ChangeProductOutputPanel);
            app.fetchallButton = uibutton(app.ChangeProductOutputPanel, 'push');
            app.fetchallButton.ButtonPushedFcn = createCallbackFcn(app, @fetchallButtonPushed, true);
            app.fetchallButton.Text = {'fetch all'; ''};
            app.ChangeoutLampLabel = uilabel(app.ChangeProductOutputPanel);
            app.ChangeoutLampLabel.Text = 'Change out';
            app.ChangeoutLamp = uilamp(app.ChangeProductOutputPanel);
            app.cash1rLabel_4 = uilabel(app.ChangeProductOutputPanel);
            app.cash1rLabel_4.Text = 'cash:1r';
            app.changeCash1rDisplay = uieditfield(app.ChangeProductOutputPanel, 'numeric');
            app.changeCash1rDisplay.Editable = 'off';
            app.cash5rLabel_4 = uilabel(app.ChangeProductOutputPanel);
            app.cash5rLabel_4.Text = 'cash:5r';
            app.changeCash5rDisplay = uieditfield(app.ChangeProductOutputPanel, 'numeric');
            app.changeCash5rDisplay.Editable = 'off';
            app.GoMaintainingPanel = uipanel(app.UIFigure);
            app.GoMaintainingPanel.Title = 'Go Maintaining';
            app.GoButton = uibutton(app.GoMaintainingPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.MaintainCodeEditFieldLabel = uilabel(app.GoMaintainingPanel);
            app.MaintainCodeEditFieldLabel.Text = 'Maintain Code';
            app.MaintainCodeEditField = uieditfield(app.GoMaintainingPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Panel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end